classdef MyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DaqFigure                       matlab.ui.Figure
        PlotoptionsMenu                 matlab.ui.container.Menu
        ResizeableMenu                  matlab.ui.container.Menu
        LogscalexMenu                   matlab.ui.container.Menu
        LogscaleyMenu                   matlab.ui.container.Menu
        ShowgridMenu                    matlab.ui.container.Menu
        ShowminorgridMenu               matlab.ui.container.Menu
        AcquisitionMenu                 matlab.ui.container.Menu
        NewtracebehaviorMenu            matlab.ui.container.Menu
        AddnewMenu                      matlab.ui.container.Menu
        ReplaceexistingMenu             matlab.ui.container.Menu
        AddtoaverageMenu                matlab.ui.container.Menu
        UpdatelistofprogramsMenu        matlab.ui.container.Menu
        Axes                            matlab.ui.control.UIAxes
        Tree                            matlab.ui.container.Tree
        InstrumenttracesNode            matlab.ui.container.TreeNode
        cavitytransmissionspectrumNode  matlab.ui.container.TreeNode
        Trace2Node                      matlab.ui.container.TreeNode
        AnalysistracesNode              matlab.ui.container.TreeNode
        Fit1Node                        matlab.ui.container.TreeNode
        Fit2Node                        matlab.ui.container.TreeNode
        AcquisitioninstrumentDropDownLabel  matlab.ui.control.Label
        AcquisitioninstrumentDropDown   matlab.ui.control.DropDown
        TracePanel                      matlab.ui.container.Panel
        DisplayCheckBox                 matlab.ui.control.CheckBox
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        SavedLabel                      matlab.ui.control.Label
        MeasurementsessionnameEditFieldLabel  matlab.ui.control.Label
        SessionNameEditField            matlab.ui.control.EditField
        LoadtraceButton                 matlab.ui.control.Button
        AnalysisroutineDropDownLabel    matlab.ui.control.Label
        AnalysisroutineDropDown         matlab.ui.control.DropDown
        BrowseButton                    matlab.ui.control.Button
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        BaseDirectoryEditField          matlab.ui.control.EditField
    end

    properties (Access = public)
        ProgramList = []
        Listeners = struct()
        
        max_trace_no = 100      % Maximum number of traces 
        
        default_file_ext = '.txt'
    end
    
    properties (Access = private)
        original_axes_position = []
        original_figure_position = []
    end
    
    methods (Access = private)
        function resizeAxes(app)
            position = app.DaqFigure.Position;
            
            if isempty(app.original_axes_position) || ...
                    isempty(app.original_figure_position)
                return
            end
            
            % Resize width and hight of axes
            x = app.original_axes_position(1);
            y = app.original_axes_position(2);
            
            dx = app.original_figure_position(3)- ...
                app.original_axes_position(1)-app.original_axes_position(3);
            dy = app.original_figure_position(4)- ...
                app.original_axes_position(2)-app.original_axes_position(4);
            
            w = position(3)-dx;
            h = position(4)-dy;
            
            app.Axes.OuterPosition = [x, y, w, h];
        end
    
        function acquireNewData(app, ~, EventData)
            
            % Get the currently selected instrument
            ind = app.AcquisitioninstrumentDropDown.Value;
            
            if ind == 0
                
                % No application selected
                return
            end
            
            curr_instr_name = app.ProgramList(ind).name;
            
            if ~strcmp(EventData.src_name, curr_instr_name)
                
                % Return if the data does not originates from the currently
                % selected instrument
                return
            end
            
            ExistingNodes = app.InstrumenttracesNode.Children;
            
            % Copy data from the source instrument
            NewTrace = copy(EventData.Trace);
            
            % Add a node to the trace display tree
            NewNode = uitreenode(app.InstrumenttracesNode, ...
                'NodeData',    NewTrace);
            
            if isprop(EventData, 'trace_name') && ~isempty(EventData.trace_name)
                
                % Use the trace name specified in EventData
                NewNode.Text = EventData.trace_name;
            else
                
                % Generate a new unique name for the trace
                NewNode.Text = matlab.lang.makeUniqueStrings([EventData.src_name '_trace'], ...
                    {ExistingNodes.Text});
            end
            
            plot(NewTrace, app.Axes);
            
            % If the save flag is on in EventData, save the new trace
            % making sure that unique filename is generated in order
            % not to prompt the owerwrite dialog
            if isprop(EventData, 'save') && EventData.save
                saveTrace(app, NewNode, 'create_unique_name', true);
            end
        end
    
        function saveTrace(app, TraceNode, varargin) 
            p = inputParser();
            
            % If file already exists, generate a uinique name rather than
            % show user the overwrite dialog.
            addParameter(p, 'create_unique_name', false, @islogical);
            parse(p,varargin{:});
            
            save_dir = createSessionPath(app.BaseDirectoryEditField, ...
                app.SessionNameEditField);
            
            % Append an ending to the file name if necessary
            filename = TraceNode.Text;
            [~, ~, ext] = fileparts(filename);
            
            if isempty(ext) || any(isspace(ext)) || ...
                    length(ext)<2 || length(ext)>5
                
                % Add default file extension
                filename = [filename, app.default_file_ext];
            end
            
            fullfilename = fullfile(save_dir, filename);
            
            if p.Results.make_unique_name && exist(fullfilename, 'file')~=0
                fullfilename = createUniqueFileName(fullfilename); 
            end
            
            % Save in readable format using the method of MyTrace
            save(TraceNode.NodeData, fullfilename)
        end
        
        % Functions declared in separate files
        deleteListeners(app, obj_name)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Replace UIAxes with regular axes which support more default 
            app.original_axes_position = app.Axes.OuterPosition;
            app.original_figure_position = app.DaqFigure.Position;
            
            % Read local settings
            try
                dir = getLocalSettings('measurement_base_dir');
                app.BaseDirectoryEditField.Value = dir;
            catch ME
                warning(ME.message)
            end
            
            try
                % The list of instruments is automatically populated from the
                % run files. Select programs that are enabled and can produce 
                % traces.
            
                FullProgList = getIcPrograms();
                ind = [FullProgList.enabled] & [FullProgList.data_source];
                app.ProgramList = FullProgList(ind);
                
                app.AcquisitioninstrumentDropDown.Items = [{'Select ...'}, ...
                    {app.ProgramList.title}];
                app.AcquisitioninstrumentDropDown.ItemsData = 0:length(app.ProgramList);
            catch ME
                warning(['Could not initialize the list of data acquisition programs. Error: ' ...
                    ME.message])
            end
            
            try
                C = MyCollector.instance();
                app.Listeners.Collector.NewDataWithHeaders = ...
                    addlistener(C, 'NewDataWithHeaders', @app.acquireNewData);
            catch ME
                warning(['Could not create a listener for the collector, ' ...
                    'the application will not be able to acquire new traces. Error:\n' ...
                    ME.message])
            end
        end

        % Size changed function: DaqFigure
        function DaqFigureSizeChanged(app, event)
            resizeAxes(app);
        end

        % Close request function: DaqFigure
        function DaqFigureCloseRequest(app, event)
            if ~isempty(fieldnames(app.Listeners))
                cellfun(@(x) deleteListeners(app, x), fieldnames(app.Listeners));
            end
            
            delete(app)
        end

        % Value changed function: AcquisitioninstrumentDropDown
        function AcquisitioninstrumentDropDownValueChanged(app, event)
            ind = app.AcquisitioninstrumentDropDown.Value;
            
            if ind == 0
                
                % No instrument selected
                return
            end
            
            try
                eval(app.ProgramList(ind).run_expr);
            catch ME
                errordlg(sprintf('An error occured while attempting to run %s: %s',...
                    app.ProgramList(ind).name), ME.message)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DaqFigure = uifigure('Visible', 'off');
            app.DaqFigure.Name = 'DAQ';
            app.DaqFigure.CloseRequestFcn = createCallbackFcn(app, @DaqFigureCloseRequest, true);
            app.DaqFigure.SizeChangedFcn = createCallbackFcn(app, @DaqFigureSizeChanged, true);
            app.PlotoptionsMenu = uimenu(app.DaqFigure);
            app.PlotoptionsMenu.Text = 'Plot options';
            app.ResizeableMenu = uimenu(app.PlotoptionsMenu);
            app.ResizeableMenu.Text = 'Resizeable';
            app.LogscalexMenu = uimenu(app.PlotoptionsMenu);
            app.LogscalexMenu.Text = 'Log scale x';
            app.LogscaleyMenu = uimenu(app.PlotoptionsMenu);
            app.LogscaleyMenu.Text = 'Log scale y';
            app.ShowgridMenu = uimenu(app.PlotoptionsMenu);
            app.ShowgridMenu.Text = 'Show grid';
            app.ShowminorgridMenu = uimenu(app.PlotoptionsMenu);
            app.ShowminorgridMenu.Text = 'Show minor grid';
            app.AcquisitionMenu = uimenu(app.DaqFigure);
            app.AcquisitionMenu.Text = 'Acquisition';
            app.NewtracebehaviorMenu = uimenu(app.AcquisitionMenu);
            app.NewtracebehaviorMenu.Text = 'New trace behavior';
            app.AddnewMenu = uimenu(app.NewtracebehaviorMenu);
            app.AddnewMenu.Checked = 'on';
            app.AddnewMenu.Text = 'Add new';
            app.ReplaceexistingMenu = uimenu(app.NewtracebehaviorMenu);
            app.ReplaceexistingMenu.Text = 'Replace existing';
            app.AddtoaverageMenu = uimenu(app.NewtracebehaviorMenu);
            app.AddtoaverageMenu.Text = 'Add to average';
            app.UpdatelistofprogramsMenu = uimenu(app.AcquisitionMenu);
            app.UpdatelistofprogramsMenu.Text = 'Update list of programs';
            app.Axes = uiaxes(app.DaqFigure);
            title(app.Axes, '')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.Tree = uitree(app.DaqFigure);
            app.Tree.Editable = 'on';
            app.InstrumenttracesNode = uitreenode(app.Tree);
            app.InstrumenttracesNode.Text = 'Instrument traces';
            app.cavitytransmissionspectrumNode = uitreenode(app.InstrumenttracesNode);
            app.cavitytransmissionspectrumNode.Text = '1. cavity transmission spectrum';
            app.Trace2Node = uitreenode(app.InstrumenttracesNode);
            app.Trace2Node.Text = 'Trace2';
            app.AnalysistracesNode = uitreenode(app.Tree);
            app.AnalysistracesNode.Text = 'Analysis traces';
            app.Fit1Node = uitreenode(app.AnalysistracesNode);
            app.Fit1Node.Text = 'Fit1';
            app.Fit2Node = uitreenode(app.AnalysistracesNode);
            app.Fit2Node.Text = 'Fit2';
            app.AcquisitioninstrumentDropDownLabel = uilabel(app.DaqFigure);
            app.AcquisitioninstrumentDropDownLabel.Text = 'Acquisition instrument';
            app.AcquisitioninstrumentDropDown = uidropdown(app.DaqFigure);
            app.AcquisitioninstrumentDropDown.Items = {'Select ...', 'Dpo4034Nano1', 'Option 3', 'Option 4'};
            app.AcquisitioninstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitioninstrumentDropDownValueChanged, true);
            app.AcquisitioninstrumentDropDown.Value = 'Dpo4034Nano1';
            app.TracePanel = uipanel(app.DaqFigure);
            app.TracePanel.Title = 'Trace';
            app.DisplayCheckBox = uicheckbox(app.TracePanel);
            app.DisplayCheckBox.Text = 'Display';
            app.ColorDropDownLabel = uilabel(app.TracePanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.TracePanel);
            app.SaveButton = uibutton(app.TracePanel, 'push');
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.TracePanel, 'push');
            app.DeleteButton.Text = 'Delete';
            app.SavedLabel = uilabel(app.TracePanel);
            app.SavedLabel.Text = 'Saved';
            app.MeasurementsessionnameEditFieldLabel = uilabel(app.DaqFigure);
            app.MeasurementsessionnameEditFieldLabel.Text = 'Measurement session name';
            app.SessionNameEditField = uieditfield(app.DaqFigure, 'text');
            app.LoadtraceButton = uibutton(app.DaqFigure, 'push');
            app.LoadtraceButton.Text = 'Load trace';
            app.AnalysisroutineDropDownLabel = uilabel(app.DaqFigure);
            app.AnalysisroutineDropDownLabel.Text = 'Analysis routine';
            app.AnalysisroutineDropDown = uidropdown(app.DaqFigure);
            app.AnalysisroutineDropDown.Items = {'Select ...', 'Option 2', 'Option 3', 'Option 4'};
            app.AnalysisroutineDropDown.Value = 'Select ...';
            app.BrowseButton = uibutton(app.DaqFigure, 'push');
            app.BrowseButton.Text = 'Browse';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.DaqFigure);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.BaseDirectoryEditField = uieditfield(app.DaqFigure, 'text');
            % Show the figure after all components are created
            app.DaqFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyDaq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DaqFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DaqFigure)
        end
    end
end