classdef GuiLakeshore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                      matlab.ui.Figure
        SensorPanel_1                   matlab.ui.container.Panel
        KEditFieldLabel_1               matlab.ui.control.Label
        TDisp_1                         matlab.ui.control.NumericEditField
        SensorPanel_2                   matlab.ui.container.Panel
        KEditFieldLabel_2               matlab.ui.control.Label
        TDisp_2                         matlab.ui.control.NumericEditField
        LoggerButton                    matlab.ui.control.Button
        SinglereadButton                matlab.ui.control.Button
        SensorPanel_3                   matlab.ui.container.Panel
        KEditFieldLabel_3               matlab.ui.control.Label
        TDisp_3                         matlab.ui.control.NumericEditField
        SensorPanel_4                   matlab.ui.container.Panel
        KEditFieldLabel_4               matlab.ui.control.Label
        TDisp_4                         matlab.ui.control.NumericEditField
        HeaterPanel_1                   matlab.ui.container.Panel
        HeaterrangeLabel                matlab.ui.control.Label
        HeaterRangeDropDown_1           matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        OutModeDropDown_1               matlab.ui.control.DropDown
        SetpointEditFieldLabel          matlab.ui.control.Label
        SetpointEditField_1             matlab.ui.control.NumericEditField
        ControlinputDropDownLabel       matlab.ui.control.Label
        ControlinputDropDown_1          matlab.ui.control.DropDown
        PowerupCheckBox_1               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_1  matlab.ui.control.Label
        HeaterPanel_2                   matlab.ui.container.Panel
        HeaterrangeLabel_2              matlab.ui.control.Label
        HeaterRangeDropDown_2           matlab.ui.control.DropDown
        ModeDropDownLabel_2             matlab.ui.control.Label
        OutModeDropDown_2               matlab.ui.control.DropDown
        SetpointEditField_2Label        matlab.ui.control.Label
        SetpointEditField_2             matlab.ui.control.NumericEditField
        ControlinputDropDown_2Label     matlab.ui.control.Label
        ControlinputDropDown_2          matlab.ui.control.DropDown
        PowerupCheckBox_2               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_2  matlab.ui.control.Label
        HeaterPanel_3                   matlab.ui.container.Panel
        HeaterrangeLabel_3              matlab.ui.control.Label
        HeaterRangeDropDown_3           matlab.ui.control.DropDown
        ModeDropDownLabel_3             matlab.ui.control.Label
        OutModeDropDown_3               matlab.ui.control.DropDown
        SetpointEditField_3Label        matlab.ui.control.Label
        SetpointEditField_3             matlab.ui.control.NumericEditField
        ControlinputDropDown_3Label     matlab.ui.control.Label
        ControlinputDropDown_3          matlab.ui.control.DropDown
        PowerupCheckBox_3               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_3  matlab.ui.control.Label
        HeaterPanel_4                   matlab.ui.container.Panel
        HeaterrangeLabel_4              matlab.ui.control.Label
        HeaterRangeDropDown_4           matlab.ui.control.DropDown
        ModeDropDownLabel_4             matlab.ui.control.Label
        OutModeDropDown_4               matlab.ui.control.DropDown
        SetpointEditField_4Label        matlab.ui.control.Label
        SetpointEditField_4             matlab.ui.control.NumericEditField
        ControlinputDropDown_4Label     matlab.ui.control.Label
        ControlinputDropDown_4          matlab.ui.control.DropDown
        PowerupCheckBox_4               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_4  matlab.ui.control.Label
        OutputcontrolCheckBox           matlab.ui.control.CheckBox
    end


    properties (Access = public)
        Instr % MyInstrument object
        GuiSync
        
        Lg % MyLogger object
        GuiLg % Gui of MyLogger
    end

    methods (Access = private)
        function createLinks(app)
            inp_ch = {'A', 'B', 'C', 'D'};
            
            for i = 1:4
                
                % Update the display of temperature units
                tmpelem = sprintf('KEditFieldLabel_%i', i);
                app.(tmpelem).Text = ['(' app.Instr.temp_unit ')'];
                
                % Desplay sensor names in panel titles
                tmpelem = sprintf('SensorPanel_%i', i);
                tmptag = sprintf('Instr.sens_name_%s', lower(inp_ch{i}));
                addLink(app.GuiSync, app.(tmpelem), tmptag, ...
                    'elem_prop', 'Title', 'outputProcessingFcn', ...
                    @(x)sprintf('Sensor %s: %s', inp_ch{i}, x));
                
                % Temperature displays
                tmpelem = sprintf('TDisp_%i', i);
                tmptag = sprintf('Instr.temp_%s', lower(inp_ch{i}));
                addLink(app.GuiSync, app.(tmpelem), tmptag);
                
                % Heater controls
                tmpelem = sprintf('HeaterRangeDropDown_%i', i);
                tmptag = sprintf('Instr.range_%i', i);
                addLink(app.GuiSync, app.(tmpelem), tmptag);
                
                tmpelem = sprintf('SetpointEditField_%i', i);
                tmptag = sprintf('Instr.setp_%i', i);
                addLink(app.GuiSync, app.(tmpelem), tmptag);
                
                tmpelem = sprintf('OutModeDropDown_%i', i);
                tmptag = sprintf('Instr.out_mode_%i(1)', i);
                addLink(app.GuiSync, app.(tmpelem), tmptag);
                
                tmpelem = sprintf('ControlinputDropDown_%i', i);
                tmptag = sprintf('Instr.out_mode_%i(2)', i);
                addLink(app.GuiSync, app.(tmpelem), tmptag);
                
                tmpelem = sprintf('PowerupCheckBox_%i', i);
                tmptag = sprintf('Instr.out_mode_%i(3)', i);
                addLink(app.GuiSync, app.(tmpelem), tmptag);
            end
        end
    
        function initDropDowns(app)
            try
                for i=1:4
                    tmpelem = sprintf('HeaterRangeDropDown_%i',i);
                    if i<=2
                        app.(tmpelem).Items = app.Instr.heater12_range_list;
                        app.(tmpelem).ItemsData = 0:length(app.Instr.heater12_range_list)-1;
                    else
                        app.(tmpelem).Items = app.Instr.heater34_range_list;
                        app.(tmpelem).ItemsData = 0:length(app.Instr.heater34_range_list)-1;
                    end
                    
                    tmpelem = sprintf('OutModeDropDown_%i',i);
                    app.(tmpelem).Items = app.Instr.out_mode_list;
                    app.(tmpelem).ItemsData = 0:length(app.Instr.out_mode_list)-1;
                    
                    tmpelem = sprintf('ControlinputDropDown_%i',i);
                    app.(tmpelem).Items = {'A', 'B', 'C', 'D'};
                    app.(tmpelem).ItemsData = 0:length(app.Instr.inp_list)-1;
                end
            catch
                warning('Dropdowns could not be initialized')
            end
        end
    
         % Disable or enable all heater control elements
        function updateHeaterPanelsAccess(app, bool)
            ElemList = [app.HeaterPanel_1.Children; app.HeaterPanel_2.Children;...
                app.HeaterPanel_3.Children; app.HeaterPanel_4.Children];
                
            for i=1:length(ElemList)
                Elem = ElemList(i);
                if bool
                    Elem.Enable = 'on';
                else
                    Elem.Enable = 'off';
                end
            end
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);

            initDropDowns(app);
            updateHeaterPanelsAccess(app, app.OutputcontrolCheckBox.Value);
            
            createLinks(app);
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            sync(app.Instr);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
            if ~(isa(app.Lg, 'MyLogger')&&isvalid(app.Lg))
                tu = app.Instr.temp_unit;
                names= app.Instr.inp_sens_name;
                headers = {['T ch A ',names{1},' (',tu,')'],...
                    ['T ch B ',names{2},' (',tu,')'],...
                    ['T ch C ',names{3},' (',tu,')'],...
                    ['T ch D ',names{4},' (',tu,')']};
                
                settings = getLocalSettings();
                base_dir = settings.measurement_base_dir;
                session_path = createSessionPath(base_dir, 'Lakeshore log');
                file_name = fullfile(session_path,'Temperature.log');
                
                app.Lg = MyLogger('MeasFcn', @()readAllHedged(app.Instr),...
                    'data_headers', headers,...
                    'disp_fmt', '%15s: %.3f',...
                    'file_name',file_name);
                app.GuiLg = GuiLogger(app.Lg,'name','Temperature logger');
            else
                
                % Bring logger gui to front
                fig = findfigure(app.GuiLg);
                fig.Visible = 'off';
                fig.Visible = 'on';
            end
        end

        % Value changed function: OutputcontrolCheckBox
        function OutputcontrolCheckBoxValueChanged(app, event)
            value = app.OutputcontrolCheckBox.Value;
            updateHeaterPanelsAccess(app,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.SensorPanel_1 = uipanel(app.MainFigure);
            app.SensorPanel_1.Title = 'Sensor A';
            app.KEditFieldLabel_1 = uilabel(app.SensorPanel_1);
            app.KEditFieldLabel_1.Text = '(K)';
            app.TDisp_1 = uieditfield(app.SensorPanel_1, 'numeric');
            app.TDisp_1.ValueDisplayFormat = '%11.3f';
            app.TDisp_1.Editable = 'off';
            app.TDisp_1.Value = 274.213;
            app.SensorPanel_2 = uipanel(app.MainFigure);
            app.SensorPanel_2.Title = 'Sensor B';
            app.KEditFieldLabel_2 = uilabel(app.SensorPanel_2);
            app.KEditFieldLabel_2.Text = '(K)';
            app.TDisp_2 = uieditfield(app.SensorPanel_2, 'numeric');
            app.TDisp_2.ValueDisplayFormat = '%11.3f';
            app.TDisp_2.Editable = 'off';
            app.TDisp_2.Value = 274.213;
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
            app.SensorPanel_3 = uipanel(app.MainFigure);
            app.SensorPanel_3.Title = 'Sensor C';
            app.KEditFieldLabel_3 = uilabel(app.SensorPanel_3);
            app.KEditFieldLabel_3.Text = '(K)';
            app.TDisp_3 = uieditfield(app.SensorPanel_3, 'numeric');
            app.TDisp_3.ValueDisplayFormat = '%11.3f';
            app.TDisp_3.Editable = 'off';
            app.TDisp_3.Value = 274.213;
            app.SensorPanel_4 = uipanel(app.MainFigure);
            app.SensorPanel_4.Title = 'Sensor D';
            app.KEditFieldLabel_4 = uilabel(app.SensorPanel_4);
            app.KEditFieldLabel_4.Text = '(K)';
            app.TDisp_4 = uieditfield(app.SensorPanel_4, 'numeric');
            app.TDisp_4.ValueDisplayFormat = '%11.3f';
            app.TDisp_4.Editable = 'off';
            app.TDisp_4.Value = 274.213;
            app.HeaterPanel_1 = uipanel(app.MainFigure);
            app.HeaterPanel_1.Title = 'Output 1';
            app.HeaterrangeLabel = uilabel(app.HeaterPanel_1);
            app.HeaterrangeLabel.Text = 'Heater range';
            app.HeaterRangeDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.HeaterRangeDropDown_1.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_1.Value = 'Medium';
            app.ModeDropDownLabel = uilabel(app.HeaterPanel_1);
            app.ModeDropDownLabel.Text = 'Mode';
            app.OutModeDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.OutModeDropDown_1.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_1.Value = 'Closed loop PID';
            app.SetpointEditFieldLabel = uilabel(app.HeaterPanel_1);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField_1 = uieditfield(app.HeaterPanel_1, 'numeric');
            app.SetpointEditField_1.Value = 273.234;
            app.ControlinputDropDownLabel = uilabel(app.HeaterPanel_1);
            app.ControlinputDropDownLabel.Text = 'Control input';
            app.ControlinputDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.ControlinputDropDown_1.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_1.Value = 'None';
            app.PowerupCheckBox_1 = uicheckbox(app.HeaterPanel_1);
            app.PowerupCheckBox_1.Text = '';
            app.RemainonafterpowercycleLabel_1 = uilabel(app.HeaterPanel_1);
            app.RemainonafterpowercycleLabel_1.Text = 'Remain on after power cycle';
            app.HeaterPanel_2 = uipanel(app.MainFigure);
            app.HeaterPanel_2.Title = 'Output 2';
            app.HeaterrangeLabel_2 = uilabel(app.HeaterPanel_2);
            app.HeaterrangeLabel_2.Text = 'Heater range';
            app.HeaterRangeDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.HeaterRangeDropDown_2.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_2.Value = 'Medium';
            app.ModeDropDownLabel_2 = uilabel(app.HeaterPanel_2);
            app.ModeDropDownLabel_2.Text = 'Mode';
            app.OutModeDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.OutModeDropDown_2.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_2.Value = 'Closed loop PID';
            app.SetpointEditField_2Label = uilabel(app.HeaterPanel_2);
            app.SetpointEditField_2Label.Text = 'Setpoint';
            app.SetpointEditField_2 = uieditfield(app.HeaterPanel_2, 'numeric');
            app.SetpointEditField_2.Value = 273.234;
            app.ControlinputDropDown_2Label = uilabel(app.HeaterPanel_2);
            app.ControlinputDropDown_2Label.Text = 'Control input';
            app.ControlinputDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.ControlinputDropDown_2.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_2.Value = 'None';
            app.PowerupCheckBox_2 = uicheckbox(app.HeaterPanel_2);
            app.PowerupCheckBox_2.Text = '';
            app.RemainonafterpowercycleLabel_2 = uilabel(app.HeaterPanel_2);
            app.RemainonafterpowercycleLabel_2.Text = 'Remain on after power cycle';
            app.HeaterPanel_3 = uipanel(app.MainFigure);
            app.HeaterPanel_3.Title = 'Output 3';
            app.HeaterrangeLabel_3 = uilabel(app.HeaterPanel_3);
            app.HeaterrangeLabel_3.Text = 'Heater range';
            app.HeaterRangeDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.HeaterRangeDropDown_3.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_3.Value = 'Medium';
            app.ModeDropDownLabel_3 = uilabel(app.HeaterPanel_3);
            app.ModeDropDownLabel_3.Text = 'Mode';
            app.OutModeDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.OutModeDropDown_3.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_3.Value = 'Closed loop PID';
            app.SetpointEditField_3Label = uilabel(app.HeaterPanel_3);
            app.SetpointEditField_3Label.Text = 'Setpoint';
            app.SetpointEditField_3 = uieditfield(app.HeaterPanel_3, 'numeric');
            app.SetpointEditField_3.Value = 273.234;
            app.ControlinputDropDown_3Label = uilabel(app.HeaterPanel_3);
            app.ControlinputDropDown_3Label.Text = 'Control input';
            app.ControlinputDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.ControlinputDropDown_3.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_3.Value = 'None';
            app.PowerupCheckBox_3 = uicheckbox(app.HeaterPanel_3);
            app.PowerupCheckBox_3.Text = '';
            app.RemainonafterpowercycleLabel_3 = uilabel(app.HeaterPanel_3);
            app.RemainonafterpowercycleLabel_3.Text = 'Remain on after power cycle';
            app.HeaterPanel_4 = uipanel(app.MainFigure);
            app.HeaterPanel_4.Title = 'Output 4';
            app.HeaterrangeLabel_4 = uilabel(app.HeaterPanel_4);
            app.HeaterrangeLabel_4.Text = 'Heater range';
            app.HeaterRangeDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.HeaterRangeDropDown_4.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_4.Value = 'Medium';
            app.ModeDropDownLabel_4 = uilabel(app.HeaterPanel_4);
            app.ModeDropDownLabel_4.Text = 'Mode';
            app.OutModeDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.OutModeDropDown_4.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_4.Value = 'Closed loop PID';
            app.SetpointEditField_4Label = uilabel(app.HeaterPanel_4);
            app.SetpointEditField_4Label.Text = 'Setpoint';
            app.SetpointEditField_4 = uieditfield(app.HeaterPanel_4, 'numeric');
            app.SetpointEditField_4.Value = 273.234;
            app.ControlinputDropDown_4Label = uilabel(app.HeaterPanel_4);
            app.ControlinputDropDown_4Label.Text = 'Control input';
            app.ControlinputDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.ControlinputDropDown_4.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_4.Value = 'None';
            app.PowerupCheckBox_4 = uicheckbox(app.HeaterPanel_4);
            app.PowerupCheckBox_4.Text = '';
            app.RemainonafterpowercycleLabel_4 = uilabel(app.HeaterPanel_4);
            app.RemainonafterpowercycleLabel_4.Text = 'Remain on after power cycle';
            app.OutputcontrolCheckBox = uicheckbox(app.MainFigure);
            app.OutputcontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputcontrolCheckBoxValueChanged, true);
            app.OutputcontrolCheckBox.Text = 'Output control';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiLakeshore(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end