classdef GuiNa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkAnalyzerUIFigure       matlab.ui.Figure
        RefreshpanelButton            matlab.ui.control.Button
        CapturetraceButton            matlab.ui.control.Button
        StartfreqMHzEditFieldLabel    matlab.ui.control.Label
        StartfreqMHzEditField         matlab.ui.control.NumericEditField
        StopfreqMHzEditFieldLabel     matlab.ui.control.Label
        StopfreqMHzEditField          matlab.ui.control.NumericEditField
        IFBWHzEditFieldLabel          matlab.ui.control.Label
        IFBWHzEditField               matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        ProbepowerdBEditFieldLabel    matlab.ui.control.Label
        ProbepowerdBEditField         matlab.ui.control.NumericEditField
        SweeptypeDropDownLabel        matlab.ui.control.Label
        SweeptypeDropDown             matlab.ui.control.DropDown
        CenterfreqMHzEditFieldLabel   matlab.ui.control.Label
        CenterfreqMHzEditField        matlab.ui.control.NumericEditField
        SpanMHzEditFieldLabel         matlab.ui.control.Label
        SpanMHzEditField              matlab.ui.control.NumericEditField
        NaveragesEditFieldLabel       matlab.ui.control.Label
        NaveragesEditField            matlab.ui.control.NumericEditField
        OutputButton                  matlab.ui.control.StateButton
        Trace1Axes                    matlab.ui.control.UIAxes
        Trace2Axes                    matlab.ui.control.UIAxes
        TriggerButtonGroup            matlab.ui.container.ButtonGroup
        HoldButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        TracetoDaqButton              matlab.ui.control.Button
        MeasurementPanel              matlab.ui.container.Panel
        MeasPar1DropDown              matlab.ui.control.DropDown
        Trace1Label                   matlab.ui.control.Label
        Trace1FormatDropDown          matlab.ui.control.DropDown
        Trace2Label                   matlab.ui.control.Label
        Trace2FormatDropDown          matlab.ui.control.DropDown
        MeasPar2DropDown              matlab.ui.control.DropDown
        ParameterLabel                matlab.ui.control.Label
        DataformatLabel               matlab.ui.control.Label
        NtracesDropDownLabel          matlab.ui.control.Label
        NtracesDropDown               matlab.ui.control.DropDown
        SelecttraceDropDownLabel      matlab.ui.control.Label
        SelecttraceDropDown           matlab.ui.control.DropDown
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        name
        Instr % MyInstrument object
        linked_elem_list={};
        Listeners
    end

    
    methods (Access = private)
        
        function updateTraceCtrlAccess(app)
            if app.Instr.trace_no > 1
                app.Trace2FormatDropDown.Enable = 'on';
                app.Trace2Label.Enable = 'on';
                app.MeasPar2DropDown.Enable = 'on';
            else 
                app.Trace2FormatDropDown.Enable = 'off';
                app.Trace2Label.Enable = 'off';
                app.MeasPar2DropDown.Enable = 'off';
            end
        end
       
        function lbl = yLabelFromForm(app, nTrace)
            fmt_tag = sprintf('form%i', nTrace);
            switch app.Instr.(fmt_tag)
                case 'MLOG'
                    lbl = 'r [dB]';
                case 'MLIN'
                    lbl = 'r';
                case 'PHAS'
                    lbl = 'Phase [rad]';
                otherwise
                    lbl = 'Y';
            end
        end
    end

    
    methods (Access = public)
        
        % creating a generic 
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end

        
        function createLinks(app)
            linkGuiElement(app, app.StartfreqMHzEditField, 'Instr.start_freq', ...
                'input_presc',1e-6);
            linkGuiElement(app, app.CenterfreqMHzEditField, 'Instr.cent_freq', ...
                'input_presc',1e-6);
            linkGuiElement(app, app.StopfreqMHzEditField, 'Instr.stop_freq', ...
                'input_presc',1e-6);
            linkGuiElement(app, app.SpanMHzEditField, 'Instr.span',...
                'input_presc',1e-6);
            linkGuiElement(app, app.IFBWHzEditField, 'Instr.ifbw');
            linkGuiElement(app, app.NaveragesEditField, 'Instr.average_no');
            linkGuiElement(app, app.NumberofpointsEditField, 'Instr.point_no');
            linkGuiElement(app, app.MeasPar1DropDown, 'Instr.meas_par1');
            linkGuiElement(app, app.MeasPar2DropDown, 'Instr.meas_par2');
            linkGuiElement(app, app.SweeptypeDropDown, 'Instr.sweep_type');
            linkGuiElement(app, app.ProbepowerdBEditField, 'Instr.power');
            linkGuiElement(app, app.OutputButton, 'Instr.enable_out');
            linkGuiElement(app, app.NtracesDropDown, 'Instr.trace_no',...
                'create_callback', false);
            linkGuiElement(app, app.Trace1FormatDropDown, 'Instr.form1',...
                'create_callback', false);
            linkGuiElement(app, app.Trace2FormatDropDown, 'Instr.form2',...
                'create_callback', false);
            linkGuiElement(app, app.SelecttraceDropDown, 'Instr.transf_n')
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            
            % make a numerical dropdown
            app.NtracesDropDown.ItemsData = str2double(app.NtracesDropDown.ItemsData);
            app.SelecttraceDropDown.ItemsData = str2double(app.SelecttraceDropDown.ItemsData);
         
            initInstrGui(app);
            createLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateTraceCtrlAccess(app);
        end

        % Close request function: NetworkAnalyzerUIFigure
        function NetworkAnalyzerUIFigureCloseRequest(app, event)
            deleteInstrGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr, 'all');
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            openDevice(app.Instr);
            readTrace(app.Instr, 1);
            %plotTrace(app.Instr.Trace1, app.Trace1Axes);
            plot(app.Trace1Axes, app.Instr.Trace1.x, app.Instr.Trace1.y);
            ylabel(app.Trace1Axes, yLabelFromForm(app, 1));
            if app.Instr.trace_no > 1
                readTrace(app.Instr, 2);
                %plotTrace(app.Instr.Trace2, app.Trace2Axes);
                plot(app.Trace2Axes, app.Instr.Trace2.x, app.Instr.Trace2.y);
                ylabel(app.Trace2Axes, yLabelFromForm(app, 2));
            end
            closeDevice(app.Instr);
        end

        % Value changed function: NtracesDropDown
        function NtracesDropDownValueChanged(app, event)
            value = int32(app.NtracesDropDown.Value);
            
            if value==1 || value==2
                writePropertyHedged(app.Instr, 'trace_no', value);
                updateTraceCtrlAccess(app);
                
            else
                error('Unknown dropdown option');
            end
        end

        % Selection changed function: TriggerButtonGroup
        function TriggerButtonGroupSelectionChanged(app, event)
            selectedButton = app.TriggerButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Hold'
                    abortSweep(app.Instr);
                case 'Single'
                    singleSweep(app.Instr);
                    app.HoldButton.Value = true;
                case 'Continuous'
                    startContSweep(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Value changed function: Trace1FormatDropDown
        function Trace1FormatDropDownValueChanged(app, event)
            value = app.Trace1FormatDropDown.Value;
            openDevice(app.Instr);
            writeTraceFormat(app.Instr, 1, value);
            readProperty(app.Instr, 'all');
            closeDevice(app.Instr);
        end

        % Value changed function: Trace2FormatDropDown
        function Trace2FormatDropDownValueChanged(app, event)
            value = app.Trace2FormatDropDown.Value;
            openDevice(app.Instr);
            writeTraceFormat(app.Instr, 2, value);
            readProperty(app.Instr, 'all');
            closeDevice(app.Instr);
        end

        % Button pushed function: TracetoDaqButton
        function TracetoDaqButtonPushed(app, event)
            % Manually trigger new data event. Presently might result in wrong header collected.
            nTrace=app.SelecttraceDropDown.Value;
            trace_tag = sprintf('Trace%i', nTrace);
            app.Instr.Trace=copy(app.Instr.(trace_tag));
            triggerNewData(app.Instr);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkAnalyzerUIFigure = uifigure;
            app.NetworkAnalyzerUIFigure.Name = 'Network Analyzer';
            app.NetworkAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @NetworkAnalyzerUIFigureCloseRequest, true);
            app.RefreshpanelButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.CapturetraceButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.StartfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StartfreqMHzEditFieldLabel.Text = 'Start freq (MHz)';
            app.StartfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.StartfreqMHzEditField.Limits = [0 3000];
            app.StartfreqMHzEditField.ValueDisplayFormat = '%.12g';
            app.StopfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StopfreqMHzEditFieldLabel.Text = 'Stop freq (MHz)';
            app.StopfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.StopfreqMHzEditField.ValueDisplayFormat = '%.12g';
            app.IFBWHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.IFBWHzEditFieldLabel.Text = 'IF BW (Hz)';
            app.IFBWHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.IFBWHzEditField.ValueDisplayFormat = '%.12g';
            app.NumberofpointsEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.NumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.ProbepowerdBEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.ProbepowerdBEditFieldLabel.Text = 'Probe power (dB)';
            app.ProbepowerdBEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.ProbepowerdBEditField.ValueDisplayFormat = '%.12g';
            app.SweeptypeDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDownLabel.Text = 'Sweep type';
            app.SweeptypeDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDown.Items = {'Linear', 'Log'};
            app.SweeptypeDropDown.ItemsData = {'LIN', 'LOG'};
            app.SweeptypeDropDown.Value = 'LIN';
            app.CenterfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.CenterfreqMHzEditFieldLabel.Text = 'Center freq (MHz)';
            app.CenterfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.CenterfreqMHzEditField.ValueDisplayFormat = '%.12g';
            app.SpanMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.SpanMHzEditField.ValueDisplayFormat = '%.12g';
            app.NaveragesEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.NaveragesEditField.ValueDisplayFormat = '%.0f';
            app.OutputButton = uibutton(app.NetworkAnalyzerUIFigure, 'state');
            app.OutputButton.Text = 'Output';
            app.Trace1Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace1Axes, 'Trace 1')
            xlabel(app.Trace1Axes, 'Frequency [Hz]')
            ylabel(app.Trace1Axes, 'Y')
            app.Trace2Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace2Axes, 'Trace 2')
            xlabel(app.Trace2Axes, 'Frequency [Hz]')
            ylabel(app.Trace2Axes, 'Y')
            app.TriggerButtonGroup = uibuttongroup(app.NetworkAnalyzerUIFigure);
            app.TriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TriggerButtonGroupSelectionChanged, true);
            app.TriggerButtonGroup.Title = 'Trigger';
            app.HoldButton = uiradiobutton(app.TriggerButtonGroup);
            app.HoldButton.Text = 'Hold';
            app.HoldButton.Value = true;
            app.SingleButton = uiradiobutton(app.TriggerButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.TriggerButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.TracetoDaqButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.TracetoDaqButton.ButtonPushedFcn = createCallbackFcn(app, @TracetoDaqButtonPushed, true);
            app.TracetoDaqButton.Text = 'Trace to Daq ';
            app.MeasurementPanel = uipanel(app.NetworkAnalyzerUIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.MeasPar1DropDown = uidropdown(app.MeasurementPanel);
            app.MeasPar1DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar1DropDown.Value = 'S21';
            app.Trace1Label = uilabel(app.MeasurementPanel);
            app.Trace1Label.Text = 'Trace 1';
            app.Trace1FormatDropDown = uidropdown(app.MeasurementPanel);
            app.Trace1FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace1FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace1FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace1FormatDropDownValueChanged, true);
            app.Trace1FormatDropDown.Value = 'MLOG';
            app.Trace2Label = uilabel(app.MeasurementPanel);
            app.Trace2Label.Enable = 'off';
            app.Trace2Label.Text = 'Trace 2';
            app.Trace2FormatDropDown = uidropdown(app.MeasurementPanel);
            app.Trace2FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace2FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace2FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace2FormatDropDownValueChanged, true);
            app.Trace2FormatDropDown.Enable = 'off';
            app.Trace2FormatDropDown.Value = 'MLOG';
            app.MeasPar2DropDown = uidropdown(app.MeasurementPanel);
            app.MeasPar2DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar2DropDown.Enable = 'off';
            app.MeasPar2DropDown.Value = 'S21';
            app.ParameterLabel = uilabel(app.MeasurementPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.DataformatLabel = uilabel(app.MeasurementPanel);
            app.DataformatLabel.Text = 'Data format';
            app.NtracesDropDownLabel = uilabel(app.MeasurementPanel);
            app.NtracesDropDownLabel.Text = 'N traces';
            app.NtracesDropDown = uidropdown(app.MeasurementPanel);
            app.NtracesDropDown.Items = {'1', '2'};
            app.NtracesDropDown.ItemsData = {'1', '2'};
            app.NtracesDropDown.ValueChangedFcn = createCallbackFcn(app, @NtracesDropDownValueChanged, true);
            app.NtracesDropDown.Value = '1';
            app.SelecttraceDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SelecttraceDropDownLabel.Text = 'Select trace';
            app.SelecttraceDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.SelecttraceDropDown.Items = {'1', '2'};
            app.SelecttraceDropDown.ItemsData = {'1', '2'};
            app.SelecttraceDropDown.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiNa(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkAnalyzerUIFigure)
        end
    end
end