classdef GuiRsa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixRSAUIFigure          matlab.ui.Figure
        StartfreqMHzEditFieldLabel    matlab.ui.control.Label
        StartfreqMHzEditField         matlab.ui.control.NumericEditField
        StopfreqMHzEditFieldLabel     matlab.ui.control.Label
        StopfreqMHzEditField          matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel           matlab.ui.control.Label
        RBWHzEditField                matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        CenterfreqMHzEditFieldLabel   matlab.ui.control.Label
        CenterfreqMHzEditField        matlab.ui.control.NumericEditField
        SpanMHzEditFieldLabel         matlab.ui.control.Label
        SpanMHzEditField              matlab.ui.control.NumericEditField
        NaveragesEditFieldLabel       matlab.ui.control.Label
        NaveragesEditField            matlab.ui.control.NumericEditField
        CapturetraceButton            matlab.ui.control.Button
        RefreshpanelButton            matlab.ui.control.Button
        ContinuouscaptureCheckBox     matlab.ui.control.CheckBox
        FindinstrumentButton          matlab.ui.control.Button
        AverageCheckBox               matlab.ui.control.CheckBox
        TraceDropDownLabel            matlab.ui.control.Label
        TraceDropDown                 matlab.ui.control.DropDown
        EnabledtracesPanel            matlab.ui.container.Panel
        Tr4Button                     matlab.ui.control.StateButton
        Tr3Button                     matlab.ui.control.StateButton
        Tr2Button                     matlab.ui.control.StateButton
        Tr1Button                     matlab.ui.control.StateButton
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
    end

    methods (Access = private)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            linkControlElement(app, app.element, 'instr_property');
            %....
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            app.Instr = MyInstr(interface, address, varargin{:}); % Create an instrument instance
            
            % Display instrument's name if given
            if ~isempty(app.Instr.name)
                app.TektronixRSAUIFigure.Name = char(app.Instr.name);
            end
            
            % initialization of dropdown menus (convert text to numbers if necessary)
            app.DropDown.ItemsData = str2double(app.DropDown.Items);
         
            %createControlLinks(app);
            %readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Close request function: TektronixRSAUIFigure
        function TektronixRSAUIFigureCloseRequest(app, event)
            delete(app.Instr);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixRSAUIFigure = uifigure;
            app.TektronixRSAUIFigure.Name = 'Tektronix RSA';
            app.TektronixRSAUIFigure.CloseRequestFcn = createCallbackFcn(app, @TektronixRSAUIFigureCloseRequest, true);
            app.StartfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StartfreqMHzEditFieldLabel.Text = 'Start freq (MHz)';
            app.StartfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StartfreqMHzEditField.Limits = [0 3000];
            app.StopfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StopfreqMHzEditFieldLabel.Text = 'Stop freq (MHz)';
            app.StopfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.RBWHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.RBWHzEditField.ValueDisplayFormat = '%e';
            app.NumberofpointsEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.CenterfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.CenterfreqMHzEditFieldLabel.Text = 'Center freq (MHz)';
            app.CenterfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.SpanMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.NaveragesEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.CapturetraceButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.ContinuouscaptureCheckBox = uicheckbox(app.TektronixRSAUIFigure);
            app.ContinuouscaptureCheckBox.Text = 'Continuous capture';
            app.FindinstrumentButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
            app.AverageCheckBox = uicheckbox(app.TektronixRSAUIFigure);
            app.AverageCheckBox.Text = 'Average?';
            app.TraceDropDownLabel = uilabel(app.TektronixRSAUIFigure);
            app.TraceDropDownLabel.Text = 'Trace';
            app.TraceDropDown = uidropdown(app.TektronixRSAUIFigure);
            app.TraceDropDown.Items = {'1', '2', '3', '4'};
            app.TraceDropDown.Value = '1';
            app.EnabledtracesPanel = uipanel(app.TektronixRSAUIFigure);
            app.EnabledtracesPanel.Title = 'Enabled traces';
            app.Tr4Button = uibutton(app.EnabledtracesPanel, 'state');
            app.Tr4Button.Text = '4';
            app.Tr3Button = uibutton(app.EnabledtracesPanel, 'state');
            app.Tr3Button.Text = '3';
            app.Tr2Button = uibutton(app.EnabledtracesPanel, 'state');
            app.Tr2Button.Text = '2';
            app.Tr1Button = uibutton(app.EnabledtracesPanel, 'state');
            app.Tr1Button.Text = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiRsa(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixRSAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixRSAUIFigure)
        end
    end
end