classdef GuiScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixDPOUIFigure         matlab.ui.Figure
        CapturetraceButton           matlab.ui.control.Button
        RefreshpanelButton           matlab.ui.control.Button
        ContinuouscaptureCheckBox    matlab.ui.control.CheckBox
        FindinstrumentButton         matlab.ui.control.Button
        AquisitionButtonGroup        matlab.ui.container.ButtonGroup
        StopButton                   matlab.ui.control.RadioButton
        SingleButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        ChannelconfigurationPanel    matlab.ui.container.Panel
        CouplingLabel                matlab.ui.control.Label
        ImpedanceLabel               matlab.ui.control.Label
        OffsetVLabel                 matlab.ui.control.Label
        ScaleVDivLabel               matlab.ui.control.Label
        CouplingCH                   matlab.ui.control.DropDown
        ImpedanceCH                  matlab.ui.control.DropDown
        OffsetCH                     matlab.ui.control.NumericEditField
        ScaleCH                      matlab.ui.control.NumericEditField
        VScalePlusButton             matlab.ui.control.Button
        VScaleMinusButton            matlab.ui.control.Button
        ChannelDropDownLabel         matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        TriggerPanel                 matlab.ui.container.Panel
        ModeDropDownLabel            matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        SourceDropDown               matlab.ui.control.DropDown
        EdgeDropDownLabel            matlab.ui.control.Label
        EdgeDropDown                 matlab.ui.control.DropDown
        LevelVEditFieldLabel         matlab.ui.control.Label
        LevelVEditField              matlab.ui.control.NumericEditField
        TimescalesDivEditFieldLabel  matlab.ui.control.Label
        TimescalesDivEditField       matlab.ui.control.NumericEditField
        NumberofpointsDropDownLabel  matlab.ui.control.Label
        NumberofpointsDropDown       matlab.ui.control.DropDown
        TraceAxes                    matlab.ui.control.UIAxes
        TimeScalePlusButton          matlab.ui.control.Button
        TimeScaleMinusButton         matlab.ui.control.Button
        EnabledchannelsPanel         matlab.ui.container.Panel
        CH4Button                    matlab.ui.control.StateButton
        CH3Button                    matlab.ui.control.StateButton
        CH2Button                    matlab.ui.control.StateButton
        CH1Button                    matlab.ui.control.StateButton
    end


    properties (Access = public)
        Instr % Instrument object
        linked_elem_list={};
    end

    methods (Access = private)
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        function updateChannelTags(app, n_ch)
            % re-link the channel property control elements to the new channel  
            app.CouplingCH.Tag=sprintf('cpl%i',n_ch);
            app.ImpedanceCH.Tag=sprintf('imp%i',n_ch);
            app.OffsetCH.Tag=sprintf('offset%i',n_ch);
            app.ScaleCH.Tag=sprintf('scale%i',n_ch);
        end
        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.ChannelDropDown, 'channel');
            linkControlElement(app, ...
                app.NumberofpointsDropDown, 'point_no', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            linkControlElement(app,...
                app.TimescalesDivEditField, 'time_scale', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            % trigger panel
            linkControlElement(app, app.SourceDropDown, 'trig_source', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ModeDropDown, 'trig_mode', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'trig_mode', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.EdgeDropDown, 'trig_slope', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'trig_slope', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.LevelVEditField, 'trig_lev', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            % channel-specific elemnts are initialized first with dummy tags
            linkControlElement(app, app.CouplingCH, '', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ImpedanceCH, '', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'imp1', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ScaleCH, '', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.OffsetCH, '', ...
                'create_callback_fcn', @app.createGenericCallback);
            % assign tags based on the channel selection
            updateChannelTags(app, app.Instr.channel);
            
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                c_tag = sprintf('enable%i',i);
                linkControlElement(app, app.(b_tag), c_tag, ...
                'create_callback_fcn', @app.createGenericCallback);
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            app.Instr = MyScope(interface, address, varargin{:});
            
            % initialization of dropdown menus
            app.ChannelDropDown.ItemsData = str2double(app.ChannelDropDown.Items);
            app.NumberofpointsDropDown.Items=...
                cellfun(@num2str, app.Instr.CommandList.point_no.val_list,...
                'UniformOutput',false);
            app.NumberofpointsDropDown.ItemsData=...
                app.Instr.CommandList.point_no.val_list;
            
            if app.Instr.acq_state
                app.ContinuousButton.Value = true;
            else
                app.StopButton.Value = true;
            end
            
            readPropertyHedged(app.Instr,'ctrl_channel');
            writePropertyHedged(app.Instr,'channel',app.Instr.ctrl_channel);
            b_tag = sprintf('CH%iButton',app.Instr.ctrl_channel);
            app.(b_tag).Enable='off';
            
            createControlLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            openDevice(app.Instr);
            readTrace(app.Instr);
            plotTrace(app.Instr.Trace, app.TraceAxes);
            app.TraceAxes.XLabel.String = app.Instr.Trace.label_x;
            app.TraceAxes.YLabel.String = app.Instr.Trace.label_y;
            closeDevice(app.Instr);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Value changed function: ContinuouscaptureCheckBox
        function ContinuouscaptureCheckBoxValueChanged(app, event)
            % disable channel switching, the remaining paprameters can 
            % be changed on fly
            app.ChannelDropDown.Enable='off';
            while app.ContinuouscaptureCheckBox.Value
                openDevice(app.Instr);
                readTrace(app.Instr);
                plotTrace(app.Instr.Trace, app.TraceAxes);
                app.TraceAxes.XLabel.String = app.Instr.Trace.label_x;
                app.TraceAxes.YLabel.String = app.Instr.Trace.label_y;
                % somehow need to wait before next acquisition
                pause(0.2);
            end
            closeDevice(app.Instr);
            updateGui(app);
            app.ChannelDropDown.Enable='on';
        end

        % Close request function: TektronixDPOUIFigure
        function TektronixDPOUIFigureCloseRequest(app, event)
            delete(app.Instr);
            delete(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Selection changed function: AquisitionButtonGroup
        function AquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    writePropertyHedged(app.Instr, 'acq_state', false);
                case 'Single'
                    acquireSingle(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    acquireContinuous(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Button pushed function: TimeScalePlusButton
        function TimeScalePlusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', -1);
            readPropertyHedged(app.Instr,'time_scale');
            updateGui(app);
        end

        % Button pushed function: TimeScaleMinusButton
        function TimeScaleMinusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', 1);
            readPropertyHedged(app.Instr,'time_scale');
            updateGui(app);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            n_ch = app.ChannelDropDown.Value;
            % re-link the channel property control elements to the new channel  
            updateChannelTags(app, n_ch);
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                if i==n_ch
                    app.(b_tag).Enable='off';
                else
                    app.(b_tag).Enable='on';
                end
            end
            enable_cmd=sprintf('enable%i',n_ch);
            writePropertyHedged(app.Instr, enable_cmd, true, 'channel', n_ch);
            updateGui(app);
        end

        % Button pushed function: VScalePlusButton
        function VScalePlusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), -1);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: VScaleMinusButton
        function VScaleMinusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), 1);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Value changed function: CH1Button
        function CH1ButtonValueChanged(app, event)
            value = app.CH1Button.Value;
            enable_state = logical(app.EnabledButton.Value);
            n_ch = app.ChannelDropDown.Value;
            cmd=sprintf('enable%i',n_ch);
            writePropertyHedged(app.Instr, cmd, enable_state);
            % If channel is disabled, disable trace fetching
            if app.Instr.(cmd)
                disp(1);
                app.CapturetraceButton.Enable='on';
                app.ContinuouscaptureCheckBox.Enable='on';
            else
                disp(2);
                app.CapturetraceButton.Enable='off';
                app.ContinuouscaptureCheckBox.Enable='off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixDPOUIFigure = uifigure;
            app.TektronixDPOUIFigure.Name = 'Tektronix DPO';
            app.TektronixDPOUIFigure.CloseRequestFcn = createCallbackFcn(app, @TektronixDPOUIFigureCloseRequest, true);
            app.CapturetraceButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.ContinuouscaptureCheckBox = uicheckbox(app.TektronixDPOUIFigure);
            app.ContinuouscaptureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuouscaptureCheckBoxValueChanged, true);
            app.ContinuouscaptureCheckBox.Text = 'Continuous capture';
            app.FindinstrumentButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
            app.AquisitionButtonGroup = uibuttongroup(app.TektronixDPOUIFigure);
            app.AquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AquisitionButtonGroupSelectionChanged, true);
            app.AquisitionButtonGroup.Title = 'Aquisition';
            app.StopButton = uiradiobutton(app.AquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ChannelconfigurationPanel = uipanel(app.TektronixDPOUIFigure);
            app.ChannelconfigurationPanel.Title = 'Channel configuration';
            app.CouplingLabel = uilabel(app.ChannelconfigurationPanel);
            app.CouplingLabel.Text = 'Coupling';
            app.ImpedanceLabel = uilabel(app.ChannelconfigurationPanel);
            app.ImpedanceLabel.Text = 'Impedance';
            app.OffsetVLabel = uilabel(app.ChannelconfigurationPanel);
            app.OffsetVLabel.Text = 'Offset [V]';
            app.ScaleVDivLabel = uilabel(app.ChannelconfigurationPanel);
            app.ScaleVDivLabel.Text = 'Scale [V/Div]';
            app.CouplingCH = uidropdown(app.ChannelconfigurationPanel);
            app.CouplingCH.Items = {'DC', 'AC'};
            app.CouplingCH.Value = 'DC';
            app.ImpedanceCH = uidropdown(app.ChannelconfigurationPanel);
            app.ImpedanceCH.Items = {'1 Mohm', '50 Ohm'};
            app.ImpedanceCH.ItemsData = {'MEG', 'FIFty'};
            app.ImpedanceCH.Value = 'MEG';
            app.OffsetCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.OffsetCH.ValueDisplayFormat = '%5.2e';
            app.ScaleCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ScaleCH.ValueDisplayFormat = '%5.2e';
            app.VScalePlusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @VScalePlusButtonPushed, true);
            app.VScalePlusButton.Text = '+';
            app.VScaleMinusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @VScaleMinusButtonPushed, true);
            app.VScaleMinusButton.Text = '–';
            app.ChannelDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.TriggerPanel = uipanel(app.TektronixDPOUIFigure);
            app.TriggerPanel.Title = 'Trigger';
            app.ModeDropDownLabel = uilabel(app.TriggerPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TriggerPanel);
            app.ModeDropDown.Items = {'Auto', 'Normal'};
            app.ModeDropDown.ItemsData = {'AUTO', 'NORMal'};
            app.ModeDropDown.Value = 'AUTO';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'AUX', 'EXT', 'LINE'};
            app.SourceDropDown.Value = 'AUX';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.EdgeDropDown = uidropdown(app.TriggerPanel);
            app.EdgeDropDown.Items = {'Rise', 'Fall'};
            app.EdgeDropDown.ItemsData = {'RISe', 'FALL'};
            app.EdgeDropDown.Value = 'RISe';
            app.LevelVEditFieldLabel = uilabel(app.TriggerPanel);
            app.LevelVEditFieldLabel.Text = 'Level [V]';
            app.LevelVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TimescalesDivEditFieldLabel = uilabel(app.TektronixDPOUIFigure);
            app.TimescalesDivEditFieldLabel.Text = 'Time scale [s/Div]';
            app.TimescalesDivEditField = uieditfield(app.TektronixDPOUIFigure, 'numeric');
            app.TimescalesDivEditField.ValueDisplayFormat = '%5.2e';
            app.NumberofpointsDropDownLabel = uilabel(app.TektronixDPOUIFigure);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.TektronixDPOUIFigure);
            app.TraceAxes = uiaxes(app.TektronixDPOUIFigure);
            xlabel(app.TraceAxes, 'Time')
            ylabel(app.TraceAxes, 'Voltage')
            app.TimeScalePlusButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.TimeScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScalePlusButtonPushed, true);
            app.TimeScalePlusButton.Text = '+';
            app.TimeScaleMinusButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.TimeScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScaleMinusButtonPushed, true);
            app.TimeScaleMinusButton.Text = '–';
            app.EnabledchannelsPanel = uipanel(app.TektronixDPOUIFigure);
            app.EnabledchannelsPanel.Title = 'Enabled channels';
            app.CH4Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH4Button.Text = 'CH4';
            app.CH3Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH3Button.Text = 'CH3';
            app.CH2Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH2Button.Text = 'CH2';
            app.CH1Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH1Button.ValueChangedFcn = createCallbackFcn(app, @CH1ButtonValueChanged, true);
            app.CH1Button.Text = 'CH1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiScope(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixDPOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixDPOUIFigure)
        end
    end
end