classdef GuiTlb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TLBdiodelaserUIFigure         matlab.ui.Figure
        ParametersPanel               matlab.ui.container.Panel
        WavelengthnmLabel             matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        PowermWEditFieldLabel         matlab.ui.control.Label
        PowermWEditField              matlab.ui.control.NumericEditField
        DiodecurrentmAEditFieldLabel  matlab.ui.control.Label
        DiodecurrentmAEditField       matlab.ui.control.NumericEditField
        DiodetemperatureCEditFieldLabel  matlab.ui.control.Label
        DiodetemperatureCEditField    matlab.ui.control.NumericEditField
        SetpointsPanel                matlab.ui.container.Panel
        PowersetpointEditField        matlab.ui.control.NumericEditField
        WavelengthtrackCheckBox       matlab.ui.control.CheckBox
        ConstantpowerCheckBox         matlab.ui.control.CheckBox
        WlPlusButton                  matlab.ui.control.Button
        WlMinusButton                 matlab.ui.control.Button
        IncrementnmEditFieldLabel     matlab.ui.control.Label
        IncrementnmEditField          matlab.ui.control.NumericEditField
        WlsetpointEditField           matlab.ui.control.NumericEditField
        CurrentsetpointEditField      matlab.ui.control.NumericEditField
        ControlmodeDropDownLabel      matlab.ui.control.Label
        ControlmodeDropDown           matlab.ui.control.DropDown
        LaseroutputButton             matlab.ui.control.StateButton
        SetmaxpowerButton             matlab.ui.control.Button
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = '' ; % App name to clear on exit;
        UpdTimer; % Timer for gui update
    end
    
    methods (Access = public)
        % This function is overloaded and does not use writePropertyHedged as
        % opening and closing device in this case consumes time and is not necessary
        function genericValueChanged(app, event)
            val = event.Value;
            % scale the value if the control element has a prescaler
            if isprop(event.Source, 'InputPrescaler')
                val = val/event.Source.InputPrescaler;
            end
            prop = sscanf(event.Source.Tag, 'Instr.%s');
            openDevice(app.Instr); % re-opening an already open device does not throw an error
            writeProperty(app.Instr, prop, val);
            readProperty(app.Instr, 'all');
            updateGui(app);
        end
    
        function updateGui(app)
            updateLinkedGuiElements(app);
            updatePanelAccess(app);
        end
    
        function updatePanelAccess(app)
            if ~app.ConstantpowerCheckBox.Value
                app.PowersetpointEditField.Enable = 'off';
                app.CurrentsetpointEditField.Enable = 'on';
            else
                app.PowersetpointEditField.Enable = 'on';
                app.CurrentsetpointEditField.Enable = 'off';
            end
            if ~app.WavelengthtrackCheckBox.Value
                app.WlsetpointEditField.Enable = 'off';
                app.WlPlusButton.Enable = 'off';
                app.WlMinusButton.Enable = 'off';
            else
                app.WlsetpointEditField.Enable = 'on';
                app.WlPlusButton.Enable = 'on';
                app.WlMinusButton.Enable = 'on';
            end
            % Constant power mode should not be switched when output is on
            if app.Instr.enable_output
                app.ConstantpowerCheckBox.Enable = 'off';
            else
                app.ConstantpowerCheckBox.Enable = 'on';
            end
        end
    end

    methods (Access = private)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            linkGuiElementToCommand(app, ...
                app.WavelengthnmEditField, 'wavelength',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.PowermWEditField, 'power',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.DiodecurrentmAEditField, 'current',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.DiodetemperatureCEditField, 'temp_diode',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.PowersetpointEditField, 'power_sp',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.CurrentsetpointEditField, 'current_sp',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.WlsetpointEditField, 'wavelength_sp',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.ConstantpowerCheckBox, 'const_power',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.WavelengthtrackCheckBox, 'wavelength_track',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.LaseroutputButton, 'enable_output',...
                'create_callback_fcn', @app.createGenericCallback);
            linkGuiElementToCommand(app, ...
                app.ControlmodeDropDown, 'control_mode',...
                'create_callback_fcn', @app.createGenericCallback);
        end
    
        function updTimerFcn(app)
            try
                openDevice(app.Instr);
                readProperty(app.Instr, 'all');
            catch
            end
            updateGui(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            
            app.ControlmodeDropDown.ItemsData = app.Instr.CommandList.control_mode.val_list;
            
            createControlLinks(app);
            openDevice(app.Instr);
            readProperty(app.Instr,'all');         
            updateGui(app);
            
            % Start periodic update of the gui
            app.UpdTimer = timer(); % Create timer for gui update
            app.UpdTimer.ExecutionMode = 'FixedDelay';
            % Timer period is limited by TLB6700 driver, 
            % for which reading 10 (about all) properties takes 0.12 s.
            app.UpdTimer.Period = 0.5; %second.
            app.UpdTimer.TimerFcn = @(~,~)updTimerFcn(app);
            app.UpdTimer.BusyMode = 'drop';
            start(app.UpdTimer);
        end

        % Close request function: TLBdiodelaserUIFigure
        function TLBdiodelaserUIFigureCloseRequest(app, event)
            stop(app.UpdTimer);
            delete(app.UpdTimer);
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end

        % Button pushed function: SetmaxpowerButton
        function SetmaxpowerButtonPushed(app, event)
            % setMaxOutPower works irrespectrive if the laser is in the constant power mode
            openDevice(app.Instr);
            setMaxOutPower(app.Instr);
            readProperty(app.Instr,'all');
        end

        % Button pushed function: WlPlusButton
        function WlPlusButtonPushed(app, event)
            new_sp = app.Instr.wavelength_sp + app.IncrementnmEditField.Value;
            openDevice(app.Instr);
            writeProperty(app.Instr,'wavelength_sp', new_sp);
            readProperty(app.Instr,'all');
        end

        % Button pushed function: WlMinusButton
        function WlMinusButtonPushed(app, event)
            new_sp = app.Instr.wavelength_sp - app.IncrementnmEditField.Value;
            openDevice(app.Instr);
            writeProperty(app.Instr,'wavelength_sp', new_sp);
            readProperty(app.Instr,'all');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TLBdiodelaserUIFigure = uifigure;
            app.TLBdiodelaserUIFigure.Name = 'TLB diode laser';
            app.TLBdiodelaserUIFigure.CloseRequestFcn = createCallbackFcn(app, @TLBdiodelaserUIFigureCloseRequest, true);
            app.ParametersPanel = uipanel(app.TLBdiodelaserUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.WavelengthnmLabel = uilabel(app.ParametersPanel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.WavelengthnmEditField.ValueDisplayFormat = '%.12g';
            app.WavelengthnmEditField.Editable = 'off';
            app.WavelengthnmEditField.Value = 1550.008;
            app.PowermWEditFieldLabel = uilabel(app.ParametersPanel);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PowermWEditField.ValueDisplayFormat = '%.12g';
            app.PowermWEditField.Editable = 'off';
            app.PowermWEditField.Value = 23;
            app.DiodecurrentmAEditFieldLabel = uilabel(app.ParametersPanel);
            app.DiodecurrentmAEditFieldLabel.Text = 'Diode current (mA)';
            app.DiodecurrentmAEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DiodecurrentmAEditField.ValueDisplayFormat = '%.12g';
            app.DiodecurrentmAEditField.Editable = 'off';
            app.DiodecurrentmAEditField.Value = 10.4;
            app.DiodetemperatureCEditFieldLabel = uilabel(app.ParametersPanel);
            app.DiodetemperatureCEditFieldLabel.Text = 'Diode temperature (C)';
            app.DiodetemperatureCEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DiodetemperatureCEditField.ValueDisplayFormat = '%.12g';
            app.DiodetemperatureCEditField.Editable = 'off';
            app.DiodetemperatureCEditField.Value = 20;
            app.SetpointsPanel = uipanel(app.TLBdiodelaserUIFigure);
            app.SetpointsPanel.Title = 'Setpoints';
            app.PowersetpointEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.PowersetpointEditField.ValueDisplayFormat = '%.12g';
            app.PowersetpointEditField.Value = 15;
            app.WavelengthtrackCheckBox = uicheckbox(app.SetpointsPanel);
            app.WavelengthtrackCheckBox.Text = 'Wavelength track';
            app.WavelengthtrackCheckBox.Value = true;
            app.ConstantpowerCheckBox = uicheckbox(app.SetpointsPanel);
            app.ConstantpowerCheckBox.Text = 'Constant power';
            app.WlPlusButton = uibutton(app.SetpointsPanel, 'push');
            app.WlPlusButton.ButtonPushedFcn = createCallbackFcn(app, @WlPlusButtonPushed, true);
            app.WlPlusButton.Text = '+';
            app.WlMinusButton = uibutton(app.SetpointsPanel, 'push');
            app.WlMinusButton.ButtonPushedFcn = createCallbackFcn(app, @WlMinusButtonPushed, true);
            app.WlMinusButton.Text = '-';
            app.IncrementnmEditFieldLabel = uilabel(app.SetpointsPanel);
            app.IncrementnmEditFieldLabel.Text = 'Increment (nm)';
            app.IncrementnmEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.IncrementnmEditField.ValueDisplayFormat = '%.11g';
            app.IncrementnmEditField.Value = 0.1;
            app.WlsetpointEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.WlsetpointEditField.ValueDisplayFormat = '%.12g';
            app.CurrentsetpointEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.CurrentsetpointEditField.ValueDisplayFormat = '%.12g';
            app.ControlmodeDropDownLabel = uilabel(app.TLBdiodelaserUIFigure);
            app.ControlmodeDropDownLabel.Text = 'Control mode';
            app.ControlmodeDropDown = uidropdown(app.TLBdiodelaserUIFigure);
            app.ControlmodeDropDown.Items = {'Local', 'Remote'};
            app.ControlmodeDropDown.ItemsData = {'LOC', 'REM'};
            app.ControlmodeDropDown.Value = 'LOC';
            app.LaseroutputButton = uibutton(app.TLBdiodelaserUIFigure, 'state');
            app.LaseroutputButton.Text = 'Laser output';
            app.SetmaxpowerButton = uibutton(app.TLBdiodelaserUIFigure, 'push');
            app.SetmaxpowerButton.ButtonPushedFcn = createCallbackFcn(app, @SetmaxpowerButtonPushed, true);
            app.SetmaxpowerButton.Text = 'Set max power';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiTlb(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TLBdiodelaserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TLBdiodelaserUIFigure)
        end
    end
end