classdef GuiTpg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure               matlab.ui.Figure
        GaugePanel1              matlab.ui.container.Panel
        PEditFieldLabel          matlab.ui.control.Label
        PDisp1                   matlab.ui.control.EditField
        GaugePanel2              matlab.ui.container.Panel
        PEditField_2Label        matlab.ui.control.Label
        PDisp2                   matlab.ui.control.EditField
        LoggerButton             matlab.ui.control.Button
        SinglereadButton         matlab.ui.control.Button
        LogtoDaqButton           matlab.ui.control.Button
        TransferchDropDownLabel  matlab.ui.control.Label
        TransferchDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        name = 'TPG pressure monitor';
        Instr % MyInstrument object
        linked_elem_list={} % Lust of elements
        Listeners % Listeners for the instrument object
        GuiLg = [] % Gui of MyLogger
    end
    
    methods (Access = public)
        % overload standard updateGui
        function updateGui(app)
            updateLinkedGuiElements(app);
            try 
                app.GaugePanel1.Title = sprintf('Gauge 1: %s, %s',...
                    app.Instr.gauge_id1, app.Instr.stat1);
            catch
                app.GaugePanel1.Title = 'Gauge 1: display error';
                warning('Cannot display status of gauge 1')
            end
            try
                app.GaugePanel2.Title = sprintf('Gauge 2: %s, %s',...
                    app.Instr.gauge_id2, app.Instr.stat2);
            catch
                app.GaugePanel2.Title = 'Gauge 2: display error';
                warning('Cannot display status of gauge 2')
            end
        end
    
    end
    

    methods (Access = private)
    
        function createLinks(app)
            app.PDisp1.Tag = 'Instr.pressure_str1';
            app.linked_elem_list = [app.linked_elem_list, app.PDisp1];
            
            app.PDisp2.Tag = 'Instr.pressure_str2';
            app.linked_elem_list = [app.linked_elem_list, app.PDisp2];
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            initInstrGui(app);
            createLinks(app);
            readAllHedged(app.Instr);
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            try
                delete(app.GuiLg);
            catch
            end
            deleteInstrGui(app);
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            readAllHedged(app.Instr);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
            if (~isempty(app.GuiLg)) && isvalid(app.GuiLg)
                % Bring the window of existing logger gui to front
                fig = findfigure(app.GuiLg);
                fig.Visible = 'off';
                fig.Visible = 'on';
            else
                % Start new gui, ensuring Instr.Lg is a valid MyLogger object
                initLogger(app.Instr);
                
                Settings = getLocalSettings();
                base_dir = Settings.measurement_base_dir;
                session_path = createSessionPath(base_dir, 'Pressure log');
                file_name = fullfile(session_path,'Pressure log.txt');        
                app.Instr.Lg.save_file = file_name;
                
                app.GuiLg = GuiLogger(app.Instr.Lg,'name',[app.Instr.name,' Pressure logger']);
            end
        end

        % Button pushed function: LogtoDaqButton
        function LogtoDaqButtonPushed(app, event)
            n_ch=str2double(app.TransferchDropDown.Value);
            transferTrace(app.Instr, n_ch);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure;
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.GaugePanel1 = uipanel(app.MainFigure);
            app.GaugePanel1.Title = 'Gauge 1';
            app.PEditFieldLabel = uilabel(app.GaugePanel1);
            app.PEditFieldLabel.Text = 'P';
            app.PDisp1 = uieditfield(app.GaugePanel1, 'text');
            app.PDisp1.Editable = 'off';
            app.GaugePanel2 = uipanel(app.MainFigure);
            app.GaugePanel2.Title = 'Gauge 2';
            app.PEditField_2Label = uilabel(app.GaugePanel2);
            app.PEditField_2Label.Text = 'P';
            app.PDisp2 = uieditfield(app.GaugePanel2, 'text');
            app.PDisp2.Editable = 'off';
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
            app.LogtoDaqButton = uibutton(app.MainFigure, 'push');
            app.LogtoDaqButton.ButtonPushedFcn = createCallbackFcn(app, @LogtoDaqButtonPushed, true);
            app.LogtoDaqButton.Text = 'Log to Daq';
            app.TransferchDropDownLabel = uilabel(app.MainFigure);
            app.TransferchDropDownLabel.Text = 'Transfer ch';
            app.TransferchDropDown = uidropdown(app.MainFigure);
            app.TransferchDropDown.Items = {'1', '2'};
            app.TransferchDropDown.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiTpg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end