classdef GuiZiScopeFt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiScopeFt                      matlab.ui.Figure
        Axes                           matlab.ui.control.UIAxes
        ScopenumberEditFieldLabel      matlab.ui.control.Label
        ScopenumberEditField           matlab.ui.control.NumericEditField
        SignalinputEditFieldLabel      matlab.ui.control.Label
        SignalinputEditField           matlab.ui.control.NumericEditField
        PlotPanel                      matlab.ui.container.Panel
        ylimitmodeLabel                matlab.ui.control.Label
        YLimModeDropDown               matlab.ui.control.DropDown
        yminEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        LogYCheckBox                   matlab.ui.control.CheckBox
        LogXCheckBox                   matlab.ui.control.CheckBox
        AcquisitionPanel               matlab.ui.container.Panel
        ScopelengthptEditFieldLabel    matlab.ui.control.Label
        ScopelengthptEditField         matlab.ui.control.NumericEditField
        FFTRBWHzEditFieldLabel         matlab.ui.control.Label
        FFTRBWHzEditField              matlab.ui.control.NumericEditField
        SamplingrateptsEditFieldLabel  matlab.ui.control.Label
        SamplingrateptsEditField       matlab.ui.control.NumericEditField
        FFTlengthptEditFieldLabel      matlab.ui.control.Label
        FFTlengthptEditField           matlab.ui.control.NumericEditField
        AveragingPanel                 matlab.ui.container.Panel
        AvgtypeDropDownLabel           matlab.ui.control.Label
        AvgtypeDropDown                matlab.ui.control.DropDown
        navgEditFieldLabel             matlab.ui.control.Label
        navgEditField                  matlab.ui.control.NumericEditField
        CompletedEditFieldLabel        matlab.ui.control.Label
        CompletedEditField             matlab.ui.control.NumericEditField
        RestartaveragingButton         matlab.ui.control.Button
        TracetoDAQButton               matlab.ui.control.Button
        nchEditFieldLabel              matlab.ui.control.Label
        nchEditField                   matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Instr       % Instrument object
        GuiSync
    end
    
    properties (Access = private)
        
        SpectrumLine
        
        % If the limits of Axes should be setup, it is only true before the
        % first data is added
        init_axes = true
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            addLink(app.GuiSync, app.ScopenumberEditField, '.Instr.n_scope');
            addLink(app.GuiSync, app.nchEditField, '.Instr.n_ch');
            addLink(app.GuiSync, app.SignalinputEditField, '.Instr.signal_in');
            addLink(app.GuiSync, app.AvgtypeDropDown, '.Instr.Trace.avg_type');
            addLink(app.GuiSync, app.navgEditField, '.Instr.Trace.n_avg');
            addLink(app.GuiSync, app.CompletedEditField, '.Instr.Trace.avg_count');
            addLink(app.GuiSync, app.FFTRBWHzEditField, '.Instr.fft_rbw');
            addLink(app.GuiSync, app.FFTlengthptEditField, '.Instr.Trace.x', ...
                'outputProcessingFcn', @(x)length(x));
            
            addLink(app.GuiSync, app.SamplingrateptsEditField, '.Instr.scope_rate');
            addLink(app.GuiSync, app.ScopelengthptEditField, '.Instr.n_pt');
            
            % Axes limits
            addLink(app.GuiSync, app.yminEditField, '.Axes.YLim(1)');
            addLink(app.GuiSync, app.ymaxEditField, '.Axes.YLim(2)');
            addLink(app.GuiSync, app.YLimModeDropDown, '.Axes.YLimMode');
            addLink(app.GuiSync, app.LogYCheckBox, '.Axes.YScale', ...
                'map', {{'log', 'linear'}, {true, false}});
            addLink(app.GuiSync, app.LogXCheckBox, '.Axes.XScale', ...
                'map', {{'log', 'linear'}, {true, false}});
        end
    
        function updatePlot(app)
            
            % Plot the trace using its class method
            set(app.SpectrumLine, 'XData', app.Instr.Trace.x, 'YData', app.Instr.Trace.y);
            
            % Set x limits manually to beautify dynamic updating
            fmax = app.Instr.scope_rate/2;
            app.Axes.XLim = [0, fmax];
            
            if app.init_axes && ~isempty(app.Instr.Trace.y)
                app.init_axes = false;
                
                % Set limits converting 'single' trace y to 'double'
                app.Axes.YLim = double([min(app.Instr.Trace.y), max(app.Instr.Trace.y)]);
            end
            
            updateAll(app.GuiSync);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            
            createLinks(app);
            
            % Create an empty plot line
            app.SpectrumLine = line(app.Axes, 'XData', [], 'YData', [], 'Color', 'blue');
            
            L = addlistener(app.Instr,'NewWave',@(~,~)updatePlot(app));
            addToCleanup(app.GuiSync, L);
            
            L = addlistener(app.Instr,'NewData',@(~,~)updatePlot(app));
            addToCleanup(app.GuiSync, L);
            
            app.Instr.Trace.avg_type = 'exp';
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Button pushed function: TracetoDAQButton
        function TracetoDAQButtonPushed(app, event)
            
        % Send the present trace to Daq
            triggerNewData(app.Instr);
        end

        % Button pushed function: RestartaveragingButton
        function RestartaveragingButtonPushed(app, event)
            clearData(app.Instr.Trace);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiScopeFt = uifigure('Visible', 'off');
            app.ZiScopeFt.Name = 'Zi scope Fourier transform';
            app.Axes = uiaxes(app.ZiScopeFt);
            xlabel(app.Axes, 'Frequency (Hz)')
            ylabel(app.Axes, 'PSD (V^2/Hz)')
            app.Axes.YScale = 'log';
            app.ScopenumberEditFieldLabel = uilabel(app.ZiScopeFt);
            app.ScopenumberEditFieldLabel.Text = 'Scope number';
            app.ScopenumberEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ScopenumberEditField.Editable = 'off';
            app.ScopenumberEditField.Value = 1;
            app.SignalinputEditFieldLabel = uilabel(app.ZiScopeFt);
            app.SignalinputEditFieldLabel.Text = 'Signal input';
            app.SignalinputEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.SignalinputEditField.Editable = 'off';
            app.SignalinputEditField.Value = 1;
            app.PlotPanel = uipanel(app.ZiScopeFt);
            app.PlotPanel.Title = 'Plot';
            app.ylimitmodeLabel = uilabel(app.PlotPanel);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.PlotPanel);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.yminEditFieldLabel = uilabel(app.PlotPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotPanel, 'numeric');
            app.yminEditField.Value = 0.1;
            app.ymaxEditFieldLabel = uilabel(app.PlotPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotPanel, 'numeric');
            app.ymaxEditField.Value = 1;
            app.LogYCheckBox = uicheckbox(app.PlotPanel);
            app.LogYCheckBox.Text = 'Log Y';
            app.LogXCheckBox = uicheckbox(app.PlotPanel);
            app.LogXCheckBox.Text = 'Log X';
            app.AcquisitionPanel = uipanel(app.ZiScopeFt);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.ScopelengthptEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.ScopelengthptEditFieldLabel.Text = 'Scope length (pt)';
            app.ScopelengthptEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.ScopelengthptEditField.ValueDisplayFormat = '%.0f';
            app.ScopelengthptEditField.Value = 5000000;
            app.FFTRBWHzEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FFTRBWHzEditFieldLabel.Text = 'FFT RBW (Hz)';
            app.FFTRBWHzEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTRBWHzEditField.Editable = 'off';
            app.SamplingrateptsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SamplingrateptsEditFieldLabel.Text = 'Sampling rate (pt/s)';
            app.SamplingrateptsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTlengthptEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FFTlengthptEditFieldLabel.Text = 'FFT length (pt)';
            app.FFTlengthptEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTlengthptEditField.ValueDisplayFormat = '%.0f';
            app.FFTlengthptEditField.Editable = 'off';
            app.AveragingPanel = uipanel(app.ZiScopeFt);
            app.AveragingPanel.Title = 'Averaging';
            app.AvgtypeDropDownLabel = uilabel(app.AveragingPanel);
            app.AvgtypeDropDownLabel.Text = 'Avg type';
            app.AvgtypeDropDown = uidropdown(app.AveragingPanel);
            app.AvgtypeDropDown.Items = {'linear', 'exp'};
            app.AvgtypeDropDown.ItemsData = {'lin', 'exp'};
            app.AvgtypeDropDown.Value = 'lin';
            app.navgEditFieldLabel = uilabel(app.AveragingPanel);
            app.navgEditFieldLabel.Text = 'n avg';
            app.navgEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.navgEditField.ValueDisplayFormat = '%.0f';
            app.navgEditField.Value = 10000;
            app.CompletedEditFieldLabel = uilabel(app.AveragingPanel);
            app.CompletedEditFieldLabel.Text = 'Completed';
            app.CompletedEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.CompletedEditField.ValueDisplayFormat = '%.0f';
            app.CompletedEditField.Editable = 'off';
            app.RestartaveragingButton = uibutton(app.AveragingPanel, 'push');
            app.RestartaveragingButton.ButtonPushedFcn = createCallbackFcn(app, @RestartaveragingButtonPushed, true);
            app.RestartaveragingButton.Text = 'Restart averaging';
            app.TracetoDAQButton = uibutton(app.AveragingPanel, 'push');
            app.TracetoDAQButton.ButtonPushedFcn = createCallbackFcn(app, @TracetoDAQButtonPushed, true);
            app.TracetoDAQButton.Text = 'Trace to DAQ';
            app.nchEditFieldLabel = uilabel(app.ZiScopeFt);
            app.nchEditFieldLabel.Text = 'n ch';
            app.nchEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.nchEditField.Editable = 'off';
            app.nchEditField.Value = 1;
            % Show the figure after all components are created
            app.ZiScopeFt.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiZiScopeFt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiScopeFt)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiScopeFt)
        end
    end
end