classdef DiodeLaserScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiodelaserscanUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        TracetoDaqButton                matlab.ui.control.Button
        ScopechannelsPanel              matlab.ui.container.Panel
        DetectorDropDownLabel           matlab.ui.control.Label
        DetectorDropDown                matlab.ui.control.DropDown
        WavelengthoutDropDownLabel      matlab.ui.control.Label
        WavelengthoutDropDown           matlab.ui.control.DropDown
        ScanPanel                       matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StartwlnmEditField              matlab.ui.control.NumericEditField
        StopwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StopwlnmEditField               matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        SpeednmsEditFieldLabel          matlab.ui.control.Label
        SpeednmsEditField               matlab.ui.control.NumericEditField
        MaxspeedxnmsLabel               matlab.ui.control.Label
        SelectinstrumentsTab            matlab.ui.container.Tab
        ScopeDropDownLabel              matlab.ui.control.Label
        ScopeDropDown                   matlab.ui.control.DropDown
        LaserDropDownLabel              matlab.ui.control.Label
        LaserDropDown                   matlab.ui.control.DropDown
        SelectinstrumentfromInstrumentListLabel  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
    end


    properties (Access = public)
        name;
        Laser; % Diode laser instrument object
        Scope; % Oscilloscope instrument object
        linked_elem_list={};
        ConstructionParser;
    end
    
    properties (SetAccess = private, GetAccess = public)
        InstrList; % Private copy of InstrumentList
    end

    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            app.StartwlnmEditField.Tag = 'Laser.scan_start_wl';
            app.linked_elem_list = [app.linked_elem_list, app.StartwlnmEditField];
            
            app.StopwlnmEditField.Tag = 'Laser.scan_stop_wl';
            app.linked_elem_list = [app.linked_elem_list, app.StopwlnmEditField];
            
            app.SpeednmsEditField.Tag = 'Laser.scan_speed';
            app.linked_elem_list = [app.linked_elem_list, app.SpeednmsEditField];
        end
    
        function p = createConstructionParser(app)
            p=inputParser();
            % Ignore unmatched parameters
            p.KeepUnmatched = true;
            addParameter(p,'Laser','');
            addParameter(p,'Scope','');
            addParameter(p,'name','',@ischar);
            app.ConstructionParser=p;
        end
    
        function parseClassInputs(app,varargin)
            p=app.ConstructionParser;
            parse(p,varargin{:});
            for i=1:length(p.Parameters)
                par = p.Parameters{i};
                try
                    app.(par) = p.Results.(par);
                catch
                    error('App does not have property %s to assign input parser results',par);
                end
            end 
        end
    
        function updateInstrDropDowns(app)
            try
                app.InstrList = getLocalSettings('InstrumentList');
            catch
                warning('Could not get local InstrumentList. Gui drop-downs are not updated');
                return
            end
            menu = [{'Select instrument ...'};fieldnames(app.InstrList)];
            app.ScopeDropDown.Items = menu;
            app.LaserDropDown.Items = menu;
        end
        
        function updatePanelAccess(app)
        end
            
        function updateGui(app)
            updateLinkedGuiElements(app);
            updatePanelAccess(app);
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            createConstructionParser(app);
            %Loads parsed variables into class properties
            parseClassInputs(app,varargin{:});
            
            %Fill dopdowns with local instrument names
            updateInstrDropDowns(app);
        end

        % Close request function: DiodelaserscanUIFigure
        function DiodelaserscanUIFigureCloseRequest(app, event)
            % Do not delete laser and scope on exit
            % Clean up variable to which app handle is assigned in the global workspace
            try
                evalin('base', sprintf('clear(''%s'')', app.name));
            catch
            end
            delete(app)
        end

        % Value changed function: SpeednmsEditField, 
        % StartwlnmEditField, StopwlnmEditField
        function LaserParameterChanged(app, event)
            val = event.Value;
            % scale the value if the control element has a prescaler
            prop = sscanf(event.Source.Tag, 'Laser.%s');
            writePropertyHedged(app.Laser, prop, val);
            updateGui(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                startScan(app.Laser);
            catch
                warning('Unable to start laser scan')
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                stopScan(app.Laser);
            catch
                warning('Unable to stop laser scan')
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            try
                resetScan(app.Laser);
            catch
                warning('Unable to reset laser scan')
            end
        end

        % Value changed function: ScopeDropDown
        function ScopeDropDownValueChanged(app, event)
            entry_name = app.ScopeDropDown.Value;
            if ismember(entry_name,fieldnames(app.InstrList))
                try
                    app.Scope = runInstrumentWithGui(entry_name);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
        end

        % Value changed function: LaserDropDown
        function LaserDropDownValueChanged(app, event)
            entry_name = app.LaserDropDown.Value;
            if ismember(entry_name,fieldnames(app.InstrList))
                try
                    app.Laser = runInstrumentWithGui(entry_name);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiodelaserscanUIFigure = uifigure;
            app.DiodelaserscanUIFigure.Name = 'Diode laser scan';
            app.DiodelaserscanUIFigure.CloseRequestFcn = createCallbackFcn(app, @DiodelaserscanUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.DiodelaserscanUIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.UIAxes = uiaxes(app.ScanTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TracetoDaqButton = uibutton(app.ScanTab, 'push');
            app.TracetoDaqButton.Text = 'Trace to Daq';
            app.ScopechannelsPanel = uipanel(app.ScanTab);
            app.ScopechannelsPanel.Title = 'Scope channels';
            app.DetectorDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.DetectorDropDownLabel.Text = 'Detector ';
            app.DetectorDropDown = uidropdown(app.ScopechannelsPanel);
            app.DetectorDropDown.Items = {'1', '2', '3', '4'};
            app.DetectorDropDown.Value = '1';
            app.WavelengthoutDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.WavelengthoutDropDownLabel.Text = 'Wavelength out';
            app.WavelengthoutDropDown = uidropdown(app.ScopechannelsPanel);
            app.WavelengthoutDropDown.Items = {'1', '2', '3', '4'};
            app.WavelengthoutDropDown.Value = '1';
            app.ScanPanel = uipanel(app.ScanTab);
            app.ScanPanel.Title = 'Scan';
            app.StartButton = uibutton(app.ScanPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ScanPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StartwavelengthnmEditFieldLabel.Text = 'Start wavelength (nm)';
            app.StartwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StartwlnmEditField.ValueDisplayFormat = '%.12g';
            app.StartwlnmEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.StopwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StopwavelengthnmEditFieldLabel.Text = 'Stop wavelength (nm)';
            app.StopwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StopwlnmEditField.ValueDisplayFormat = '%.12g';
            app.StopwlnmEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.ResetButton = uibutton(app.ScanPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SpeednmsEditFieldLabel = uilabel(app.ScanPanel);
            app.SpeednmsEditFieldLabel.Text = 'Speed (nm/s)';
            app.SpeednmsEditField = uieditfield(app.ScanPanel, 'numeric');
            app.SpeednmsEditField.ValueDisplayFormat = '%.12g';
            app.SpeednmsEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.MaxspeedxnmsLabel = uilabel(app.ScanTab);
            app.MaxspeedxnmsLabel.Text = 'Max speed: x nm/s';
            app.SelectinstrumentsTab = uitab(app.TabGroup);
            app.SelectinstrumentsTab.Title = 'Select instruments';
            app.ScopeDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.SelectinstrumentsTab);
            app.ScopeDropDown.Items = {'Select instrument ...'};
            app.ScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScopeDropDownValueChanged, true);
            app.ScopeDropDown.Value = 'Select instrument ...';
            app.LaserDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.SelectinstrumentsTab);
            app.LaserDropDown.Items = {'Select instrument ...'};
            app.LaserDropDown.ValueChangedFcn = createCallbackFcn(app, @LaserDropDownValueChanged, true);
            app.LaserDropDown.Value = 'Select instrument ...';
            app.SelectinstrumentfromInstrumentListLabel = uilabel(app.SelectinstrumentsTab);
            app.SelectinstrumentfromInstrumentListLabel.Text = 'Select instrument from InstrumentList';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Label = uilabel(app.HelpTab);
            app.Label.Text = {'This program scans wavelength of ECDL laser and records transmission vs wavelength'; 'Photodiode and Wavelength output of ECDL need to be connected to oscilloscope channels '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DiodeLaserScan(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiodelaserscanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiodelaserscanUIFigure)
        end
    end
end