classdef GuiFreqNoiseCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationinfrequencyunitsUIFigure  matlab.ui.Figure
        ModulationdepthbetaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthbetaEditField  matlab.ui.control.NumericEditField
        CalibrateButton               matlab.ui.control.Button
        SpectrumtypeDropDownLabel     matlab.ui.control.Label
        SpectrumtypeDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        GuiSync
        Fnc
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.SpectrumtypeDropDown,           'Fnc.spectrum_type');           
            addLink(app.GuiSync, app.ModulationdepthbetaEditField,   'Fnc.beta');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FncObj)
            app.Fnc = FncObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', FncObj);
            createLinks(app); 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calcFreqSpectrum(app.Fnc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationinfrequencyunitsUIFigure = uifigure('Visible', 'off');
            app.CalibrationinfrequencyunitsUIFigure.Name = 'Calibration in frequency units';
            app.ModulationdepthbetaEditFieldLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.ModulationdepthbetaEditFieldLabel.Text = 'Modulation depth beta';
            app.ModulationdepthbetaEditField = uieditfield(app.CalibrationinfrequencyunitsUIFigure, 'numeric');
            app.CalibrateButton = uibutton(app.CalibrationinfrequencyunitsUIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SpectrumtypeDropDownLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.SpectrumtypeDropDownLabel.Text = 'Spectrum type';
            app.SpectrumtypeDropDown = uidropdown(app.CalibrationinfrequencyunitsUIFigure);
            app.SpectrumtypeDropDown.Items = {'S_\omega', 'S_f', 'sqrt(S_f)'};
            app.SpectrumtypeDropDown.Value = 'sqrt(S_f)';
            % Show the figure after all components are created
            app.CalibrationinfrequencyunitsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiFreqNoiseCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationinfrequencyunitsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationinfrequencyunitsUIFigure)
        end
    end
end