classdef DataAcquisitionMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataacquisitionmenuUIFigure  matlab.ui.Figure
        MainTabGroup                 matlab.ui.container.TabGroup
        ProgramTab                   matlab.ui.container.Tab
        RunButton                    matlab.ui.control.Button
        InstrumentmanagerButton      matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        ProgramsListBoxLabel         matlab.ui.control.Label
        ProgramListBox               matlab.ui.control.ListBox
        RuninbackgroundButton        matlab.ui.control.Button
        SettingsTab                  matlab.ui.container.Tab
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        MeasBaseDirEditField         matlab.ui.control.EditField
        MeasBaseDirBrowseButton      matlab.ui.control.Button
        ColorschemeDropDownLabel     matlab.ui.control.Label
        ColorschemeDropDown          matlab.ui.control.DropDown
        ClassbrowserTab              matlab.ui.container.Tab
        MyInstrOnlyCheckBox          matlab.ui.control.CheckBox
        RefreshButton                matlab.ui.control.Button
        SearchinEditFieldLabel       matlab.ui.control.Label
        ClassDirEditField            matlab.ui.control.EditField
        ClassDirBrowseButton         matlab.ui.control.Button
        ClassnameListBoxLabel        matlab.ui.control.Label
        ClassListBox                 matlab.ui.control.ListBox
        CollectorTab                 matlab.ui.container.Tab
        CollectHeadersButton         matlab.ui.control.Button
        InstrumentList               matlab.ui.control.ListBox
        InstrumentPanel              matlab.ui.container.Panel
        HeaderFlagCheckBox           matlab.ui.control.CheckBox
        DeleteinstrumentButton       matlab.ui.control.Button
        CollectHeadersCheckBox       matlab.ui.control.CheckBox
        LocalbasedirectoryEditFieldLabel  matlab.ui.control.Label
        LocalbasedirEditField        matlab.ui.control.EditField
        InfoLabel                    matlab.ui.control.Label
        HeaderTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ProgList = MyProgramDescriptor.empty();
        InstrCtrlClasses
        Collector
    end
    
    properties (Access = private)
        Listeners 
    end
    
    methods (Access = public)

        function updateProgramList(app)
            
            % Reload the list of local instrument control programs
            FullProgList = getIcPrograms();
            ind = [FullProgList.enabled];
            
            app.ProgList = FullProgList(ind);
            
            app.ProgramListBox.Items = {app.ProgList.title};
            app.ProgramListBox.ItemsData = 1:length(app.ProgList);
            
            if isempty(app.ProgramListBox.Value) && ~isempty(app.ProgList)
                app.ProgramListBox.Value = 1;
            end
            
            % Enable state of the run buttons depends on the current selection in the list
            updateRunButtonsAccess(app);
        end
        
        % Find all the matlab classes defined in dir and it sub-directories
        function ClassFiles = readClassFiles(app, dir_name)
            DirMatContent = what(dir_name);
            class_names = DirMatContent.classes;
            ClassFiles = struct();
            
            for i=1:length(class_names)
                ClassFiles.(class_names{i}) = readCommentHeader([class_names{i},'.m']);
                ClassFiles.(class_names{i}).name = class_names{i};
            end
            
            % Find sub-directories, which are not class ('@'), package ('+')
            % or git ('.') sub-directories and neither file system symbols ('.', '..')
            DirContent = dir(dir_name);
            full_names = fullfile(dir_name,{DirContent(:).name});
            
            is_sub_dir = cellfun(...
                @(x) (x(1)~='.' && x(1)~='@' && x(1)~='+' && isfolder(fullfile(dir_name,x))),...
                {DirContent(:).name});
            sub_dir_names = full_names(is_sub_dir);
            
            % Read classes in all the subfolders
            for i=1:length(sub_dir_names)
                NewClassFiles = readClassFiles(app, sub_dir_names{i});
                new_class_names = fieldnames(NewClassFiles);
                for j=1:length(new_class_names)
                    ClassFiles.(new_class_names{j}) = NewClassFiles.(new_class_names{j});
                end
            end
        end
    
        function updateClassList(app)
            ClassFiles = readClassFiles(app, app.ClassDirEditField.Value);
            app.InstrCtrlClasses = ClassFiles;
            class_names = fieldnames(ClassFiles);
            
            if app.MyInstrOnlyCheckBox.Value
                
                % Select only classes, which have MyInstrument among their superclasses
                ind = cellfun(@(x) isa(x, 'MyInstrument'), class_names);
                
                app.ClassListBox.Items = class_names(ind);
            else
                app.ClassListBox.Items = class_names;
            end
        end
        
        function updateHeaderTextArea(app)
            switch app.MainTabGroup.SelectedTab
                case app.ProgramTab
                    ind = app.ProgramListBox.Value;
                    
                    if ~isempty(ind)
                        app.HeaderTextArea.Value = app.ProgList(ind).info;
                    else
                        app.HeaderTextArea.Value = '';
                    end
                case app.SettingsTab
                    app.HeaderTextArea.Value = '';
                case app.ClassbrowserTab
                    tmp_class = app.ClassListBox.Value;
                    
                    if ~isempty(tmp_class)
                        app.HeaderTextArea.Value = app.InstrCtrlClasses.(tmp_class).comment_header;
                    else
                        app.HeaderTextArea.Value = '';
                    end
            end
        end
        
    end
    
    methods (Access = private)
        
        function updateInstrList(app)
            app.InstrumentList.Items = app.Collector.running_instruments;
            
            if ~isempty(app.InstrumentList.Items)
                name = app.InstrumentList.Items{1};
                app.InstrumentList.Value = name;
                app.InstrumentPanel.Title = name;
                app.HeaderFlagCheckBox.Enable = 'on';
                app.DeleteinstrumentButton.Enable = 'on';
                
                if isfield(app.Collector.InstrProps, name) && isfield(app.Collector.InstrProps.(name), 'header_flag')
                    app.HeaderFlagCheckBox.Enable = 'on';
                    app.HeaderFlagCheckBox.Value = app.Collector.InstrProps.(name).header_flag;
                else
                    app.HeaderFlagCheckBox.Enable = 'off';
                    app.HeaderFlagCheckBox.Value = false;
                end
            else
                app.InstrumentPanel.Title = 'Instrument Name';
                app.HeaderFlagCheckBox.Enable = 'off';
                app.DeleteinstrumentButton.Enable = 'off';
            end
        end
        
        function deleteListeners(app, obj_name)
            
            %Finds if the object has listeners in the listeners structure
            if ismember(obj_name, fieldnames(app.Listeners))
                
                %Grabs the fieldnames of the object's listeners structure
                names=fieldnames(app.Listeners.(obj_name));
                for i=1:length(names)
                    
                    %Deletes the listeners
                    delete(app.Listeners.(obj_name).(names{i}));
                    
                    %Removes the field from the structure
                    app.Listeners.(obj_name)=...
                        rmfield(app.Listeners.(obj_name),names{i});
                end
                
                %Removes the object's field from the structure
                app.Listeners=rmfield(app.Listeners, obj_name);
            end
        end
    
        function updateRunButtonsAccess(app)
            ind = app.ProgramListBox.Value;
            
            if ~isempty(ind)
                
                if isempty(app.ProgList(ind).run_expr)
                    app.RunButton.Enable = 'off';
                else
                    app.RunButton.Enable = 'on';
                end
                
                if isempty(app.ProgList(ind).run_bg_expr)
                    app.RuninbackgroundButton.Enable = 'off';
                else
                    app.RuninbackgroundButton.Enable = 'on';
                end
            else
                app.RunButton.Enable = 'off';
                app.RuninbackgroundButton.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isValidBaseVar('DaqMenu')
                closeFigure(app);
                error('DaqMenu is already running');
            end
            assignin('base','DaqMenu',app);
            
            % Get the unique instance of Collector and initialize its GUI tab
            app.Collector = MyCollector.instance();
            updateInstrList(app);
            
            app.Listeners.Collector.InstrChange =...
                addlistener(app.Collector, 'InstrProps', 'PostSet', @(~,~) updateInstrList(app));
            
            % The line below throws an error if the local directory is not set up
            app.LocalbasedirEditField.Value = getLocalBaseDir();
            
            % Set the parent directory of MyInstrument as default for search of avaliable classes
            app.ClassDirEditField.Value = getInstrumentControlDir();
            
            updateProgramList(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_expr,';']);
            end
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            updateProgramList(app);
        end

        % Button pushed function: InstrumentmanagerButton
        function InstrumentmanagerButtonPushed(app, event)
            InstrumentManager();
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            switch selectedTab
                case app.ProgramTab
                    updateProgramList(app);
                case app.SettingsTab
                    app.MeasBaseDirEditField.Value = getLocalSettings('measurement_base_dir');
                    
                    try
                        S = getLocalSettings('ColorScheme');
                        app.ColorschemeDropDown.Value = S.name;
                    catch
                    end
                case app.ClassbrowserTab
                    updateClassList(app);
                case app.CollectorTab
                    updateInstrList(app);
            end
            app.HeaderTextArea.Value = '';
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            updateClassList(app);
            updateHeaderTextArea(app);
        end

        % Value changed function: MyInstrOnlyCheckBox
        function MyInstrOnlyCheckBoxValueChanged(app, event)
            updateClassList(app);
            updateHeaderTextArea(app);
        end

        % Button pushed function: ClassDirBrowseButton
        function ClassDirBrowseButtonPushed(app, event)
            dir_name = uigetdir();
            if dir_name~=0
                
                % if a new file was choosen
                app.ClassDirEditField.Value = dir_name;
            end
            
            % Bring focus back to the window
            app.DataacquisitionmenuUIFigure.Visible='off';
            app.DataacquisitionmenuUIFigure.Visible='on';
        end

        % Callback function
        function ClassTableCellSelection(app, event)
            app.class_row_sel = event.Indices(1,1);
            updateHeaderTextArea(app);
        end

        % Button pushed function: MeasBaseDirBrowseButton
        function MeasBaseDirBrowseButtonPushed(app, event)
            dir_name = uigetdir();
            if dir_name~=0
                
                % if a new file was choosen
                app.MeasBaseDirEditField.Value = dir_name;
                setLocalSettings('measurement_base_dir', dir_name);
            end
            
            % Bring focus back to the window
            app.DataacquisitionmenuUIFigure.Visible='off';
            app.DataacquisitionmenuUIFigure.Visible='on';
        end

        % Value changed function: MeasBaseDirEditField
        function MeasBaseDirEditFieldValueChanged(app, event)
            value = app.MeasBaseDirEditField.Value;
            setLocalSettings('measurement_base_dir', value);
        end

        % Value changed function: InstrumentList
        function InstrListChangeCallback(app, event)
            value = app.InstrumentList.Value;
            app.HeaderFlagCheckBox.Value=...
                app.Collector.InstrProps.(value).header_flag;
            app.InstrumentPanel.Title=value;
        end

        % Button pushed function: CollectHeadersButton
        function CollectHeadersCallback(app, event)
            Mdt = acquireHeaders(app.Collector);
            app.HeaderTextArea.Value = mdt2str(Mdt);
        end

        % Value changed function: HeaderFlagCheckBox
        function HeaderFlagCallback(app, event)
            value = app.HeaderFlagCheckBox.Value;
            app.Collector.InstrProps.(app.InstrumentList.Value).header_flag = value;
        end

        % Close request function: DataacquisitionmenuUIFigure
        function closeFigure(app, event)
            if ~isempty(app.Listeners)
                cellfun(@(x) deleteListeners(app,x), fieldnames(app.Listeners));
            end
            delete(app)
        end

        % Value changed function: CollectHeadersCheckBox
        function CollectHeadersCheckBoxValueChange(app, event)
                value = app.CollectHeadersCheckBox.Value;
                app.Collector.collect_flag=value;
        end

        % Value changed function: ClassListBox
        function ClassListBoxValueChanged(app, event)
            updateHeaderTextArea(app);
        end

        % Value changed function: ProgramListBox
        function ProgramListBoxValueChanged(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                app.HeaderTextArea.Value = app.ProgList(ind).info;
            else
                app.HeaderTextArea.Value = '';
            end

            updateRunButtonsAccess(app);
        end

        % Button pushed function: RuninbackgroundButton
        function RuninbackgroundButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_bg_expr,';']);
            end
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            name = app.InstrumentList.Value;
            if ~isempty(name)
                deleteInstrument(app.Collector, name)
            end
            updateInstrList(app);
        end

        % Value changed function: ColorschemeDropDown
        function ColorschemeDropDownValueChanged(app, event)
            S = struct();
            S.name = lower(app.ColorschemeDropDown.Value);
            
            switch S.name
                case 'default'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'default');
                case 'dark'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'dark');
                case 'bright'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'bright');
                otherwise
                    error('Unknown color scheme %s', value)
            end
            
            % Recolor this app
            S.colorSchemeFcn(app);
            
            try
                
                % Recolor the GUIs of all running instruments
                instr_names = app.Collector.running_instruments;
                for i = 1:length(instr_names)
                    Gui = getInstrumentGui(app.Collector, instr_names{i});
                    if ~isempty(Gui)
                        S.colorSchemeFcn(Gui);
                    end
                end
            catch ME
                warning(['Could not recolor instrument GUIs. Error: ' ME.message]);
            end
            
            % Save the new color scheme
            setLocalSettings('ColorScheme', S);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataacquisitionmenuUIFigure = uifigure('Visible', 'off');
            app.DataacquisitionmenuUIFigure.Name = 'Data acquisition menu';
            app.DataacquisitionmenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFigure, true);
            app.MainTabGroup = uitabgroup(app.DataacquisitionmenuUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.ProgramTab = uitab(app.MainTabGroup);
            app.ProgramTab.Title = 'Programs';
            app.RunButton = uibutton(app.ProgramTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InstrumentmanagerButton = uibutton(app.ProgramTab, 'push');
            app.InstrumentmanagerButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentmanagerButtonPushed, true);
            app.InstrumentmanagerButton.Text = 'Instrument manager';
            app.ReloadlistButton = uibutton(app.ProgramTab, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.ProgramsListBoxLabel = uilabel(app.ProgramTab);
            app.ProgramsListBoxLabel.Text = 'Programs';
            app.ProgramListBox = uilistbox(app.ProgramTab);
            app.ProgramListBox.Items = {};
            app.ProgramListBox.ValueChangedFcn = createCallbackFcn(app, @ProgramListBoxValueChanged, true);
            app.ProgramListBox.Value = {};
            app.RuninbackgroundButton = uibutton(app.ProgramTab, 'push');
            app.RuninbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @RuninbackgroundButtonPushed, true);
            app.RuninbackgroundButton.Text = 'Run in background';
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.MeasBaseDirEditField = uieditfield(app.SettingsTab, 'text');
            app.MeasBaseDirEditField.ValueChangedFcn = createCallbackFcn(app, @MeasBaseDirEditFieldValueChanged, true);
            app.MeasBaseDirEditField.Value = 'M:\Measurement Campaigns\';
            app.MeasBaseDirBrowseButton = uibutton(app.SettingsTab, 'push');
            app.MeasBaseDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasBaseDirBrowseButtonPushed, true);
            app.MeasBaseDirBrowseButton.Text = 'Browse';
            app.ColorschemeDropDownLabel = uilabel(app.SettingsTab);
            app.ColorschemeDropDownLabel.Text = 'Color scheme';
            app.ColorschemeDropDown = uidropdown(app.SettingsTab);
            app.ColorschemeDropDown.Items = {'Default', 'Dark', 'Bright'};
            app.ColorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorschemeDropDownValueChanged, true);
            app.ColorschemeDropDown.Value = 'Default';
            app.ClassbrowserTab = uitab(app.MainTabGroup);
            app.ClassbrowserTab.Title = 'Class browser';
            app.MyInstrOnlyCheckBox = uicheckbox(app.ClassbrowserTab);
            app.MyInstrOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MyInstrOnlyCheckBoxValueChanged, true);
            app.MyInstrOnlyCheckBox.Text = {'Only subclasses'; 'of MyInstrument'};
            app.RefreshButton = uibutton(app.ClassbrowserTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SearchinEditFieldLabel = uilabel(app.ClassbrowserTab);
            app.SearchinEditFieldLabel.Text = 'Search in';
            app.ClassDirEditField = uieditfield(app.ClassbrowserTab, 'text');
            app.ClassDirBrowseButton = uibutton(app.ClassbrowserTab, 'push');
            app.ClassDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ClassDirBrowseButtonPushed, true);
            app.ClassDirBrowseButton.Text = 'Browse';
            app.ClassnameListBoxLabel = uilabel(app.ClassbrowserTab);
            app.ClassnameListBoxLabel.Text = 'Class name';
            app.ClassListBox = uilistbox(app.ClassbrowserTab);
            app.ClassListBox.Items = {};
            app.ClassListBox.ValueChangedFcn = createCallbackFcn(app, @ClassListBoxValueChanged, true);
            app.ClassListBox.Value = {};
            app.CollectorTab = uitab(app.MainTabGroup);
            app.CollectorTab.Title = 'Collector';
            app.CollectHeadersButton = uibutton(app.CollectorTab, 'push');
            app.CollectHeadersButton.ButtonPushedFcn = createCallbackFcn(app, @CollectHeadersCallback, true);
            app.CollectHeadersButton.Text = 'Collect headers';
            app.InstrumentList = uilistbox(app.CollectorTab);
            app.InstrumentList.Items = {};
            app.InstrumentList.ValueChangedFcn = createCallbackFcn(app, @InstrListChangeCallback, true);
            app.InstrumentList.Value = {};
            app.InstrumentPanel = uipanel(app.CollectorTab);
            app.InstrumentPanel.Title = 'Instrument name';
            app.HeaderFlagCheckBox = uicheckbox(app.InstrumentPanel);
            app.HeaderFlagCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeaderFlagCallback, true);
            app.HeaderFlagCheckBox.Text = 'Collect header';
            app.DeleteinstrumentButton = uibutton(app.InstrumentPanel, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.CollectHeadersCheckBox = uicheckbox(app.CollectorTab);
            app.CollectHeadersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectHeadersCheckBoxValueChange, true);
            app.CollectHeadersCheckBox.Text = 'Automatically Collect Headers';
            app.CollectHeadersCheckBox.Value = true;
            app.LocalbasedirectoryEditFieldLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.LocalbasedirectoryEditFieldLabel.Text = 'Local base directory';
            app.LocalbasedirEditField = uieditfield(app.DataacquisitionmenuUIFigure, 'text');
            app.LocalbasedirEditField.Editable = 'off';
            app.InfoLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.InfoLabel.Text = 'Info';
            app.HeaderTextArea = uitextarea(app.DataacquisitionmenuUIFigure);
            app.HeaderTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.DataacquisitionmenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAcquisitionMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataacquisitionmenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataacquisitionmenuUIFigure)
        end
    end
end