classdef InstrumentManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagethelistofinstrumentsUIFigure  matlab.ui.Figure
        DeleteinstrumentButton       matlab.ui.control.Button
        SavechangesButton            matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        InstrumentListlocationLabel  matlab.ui.control.Label
        BaseDirEditField             matlab.ui.control.EditField
        InstrumentLabel              matlab.ui.control.Label
        InstrListBox                 matlab.ui.control.ListBox
        TabGroup                     matlab.ui.container.TabGroup
        InstrumentpropertiesTab      matlab.ui.container.Tab
        DatasourceCheckBox           matlab.ui.control.CheckBox
        InterfaceEditFieldLabel      matlab.ui.control.Label
        InterfaceEditField           matlab.ui.control.EditField
        ControlclassEditFieldLabel   matlab.ui.control.Label
        ControlclassEditField        matlab.ui.control.EditField
        GUIEditFieldLabel            matlab.ui.control.Label
        GUIEditField                 matlab.ui.control.EditField
        MenutitleEditFieldLabel      matlab.ui.control.Label
        MenutitleEditField           matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        StartupoptionsButton         matlab.ui.control.Button
        EnabledCheckBox              matlab.ui.control.CheckBox
        LoggeroptionsButton          matlab.ui.control.Button
        FindinstrumentsTab           matlab.ui.container.Tab
        ConstructornameListBoxLabel  matlab.ui.control.Label
        ConstructornameListBox       matlab.ui.control.ListBox
        RefreshButton                matlab.ui.control.Button
        IDNButton                    matlab.ui.control.Button
        AssignaddrButton             matlab.ui.control.Button
        InterfaceDropDownLabel       matlab.ui.control.Label
        InterfaceDropDown            matlab.ui.control.DropDown
        AdapterDropDownLabel         matlab.ui.control.Label
        AdapterDropDown              matlab.ui.control.DropDown
        SearchstatusLabel            matlab.ui.control.Label
        AssignnewaddressLabel        matlab.ui.control.Label
        ResponseerrorLabel           matlab.ui.control.Label
        RespErrWngTextArea           matlab.ui.control.TextArea
        IDNButton2                   matlab.ui.control.Button
        AddressEditFieldLabel        matlab.ui.control.Label
        AddressEditField             matlab.ui.control.EditField
        AddinstrumentButton          matlab.ui.control.Button
    end


    properties (Access = public)   
        TmpInstrList;
        Ret % variable with the value passed by reference to return the choosen device
        
        DEFAULT_TIMEOUT = 1; % timeout for communication
        
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
        
        EDIT_BG_COLOR = [1,1,0.9]; % yellow if changes unsaved
        STD_BG_COLOR = [1,1,1]; % white
        
        STD_TEXT_COLOR = [0,0,0]; % black
    end

    methods (Access = private)
    
        function updateInstrDisp(app)
            
            % Clear text area
            app.RespErrWngTextArea.Value = '';
            instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
            app.InstrListBox.Items = instr_names;
            
            % An element of ItemsData is returned when Value property is accessed
            app.InstrListBox.ItemsData = 1:length(instr_names);
            
            if isempty(app.InstrListBox.Value) && ~isempty(app.InstrListBox.Items)
                app.InstrListBox.Value = 1;
            end
            
            ind = app.InstrListBox.Value;
            
            if ~isempty(ind)
                app.NameEditField.Value = app.TmpInstrList{ind}.name;
                app.InterfaceEditField.Value = app.TmpInstrList{ind}.interface;
                app.AddressEditField.Value = app.TmpInstrList{ind}.address;
                try
                    app.ControlclassEditField.Value = app.TmpInstrList{ind}.control_class;
                catch
                    app.ControlclassEditField.Value = '';
                end
                try
                    app.GUIEditField.Value = app.TmpInstrList{ind}.gui;
                catch
                    app.GUIEditField.Value = '';
                end
                try
                    app.MenutitleEditField.Value = app.TmpInstrList{ind}.menu_title;
                catch
                    app.MenutitleEditField.Value = '';
                end
                try
                    app.DatasourceCheckBox.Value = app.TmpInstrList{ind}.data_source;
                catch
                    app.DatasourceCheckBox.Value = false;
                end
                try
                    app.EnabledCheckBox.Value = app.TmpInstrList{ind}.enabled;
                catch
                    app.EnabledCheckBox.Value = false;
                end
                try
                    app.LoggeroptionsButton.Visible = ismethod( ...
                        app.TmpInstrList{ind}.control_class, 'createLogger');
                catch
                    app.LoggeroptionsButton.Visible = 'off';
                end
            else
                app.NameEditField.Value = '';
                app.InterfaceEditField.Value = '';
                app.AddressEditField.Value = '';
                app.ControlclassEditField.Value = '';
                app.GUIEditField.Value = '';
                app.MenutitleEditField.Value = '';
                app.DatasourceCheckBox.Value = false;
                app.EnabledCheckBox.Value = false;
                
                app.LoggeroptionsButton.Visible = 'off';
            end
        end
    
        function updateTmpInstrList(app)           
            ind = app.InstrListBox.Value;
            app.TmpInstrList{ind}.name = app.NameEditField.Value;
            app.TmpInstrList{ind}.interface = app.InterfaceEditField.Value;
            app.TmpInstrList{ind}.address = app.AddressEditField.Value;
            app.TmpInstrList{ind}.control_class = app.ControlclassEditField.Value;
            app.TmpInstrList{ind}.gui = app.GUIEditField.Value;
            app.TmpInstrList{ind}.menu_title = app.MenutitleEditField.Value;
            app.TmpInstrList{ind}.data_source = app.DatasourceCheckBox.Value;
            app.TmpInstrList{ind}.enabled = app.EnabledCheckBox.Value;
            setInstrListStatus(app, 'edits_unsaved');
        end       
    
        function setInstrListStatus(app, status)
            switch status
                case 'edits_unsaved'
                    app.InstrListBox.BackgroundColor = app.EDIT_BG_COLOR;
                    app.StatusLabel.Text = 'List of instruments contains unsaved changes';
                    app.StatusLabel.FontColor = app.WARNING_COLOR;
                case 'edits_saved'
                    app.InstrListBox.BackgroundColor = app.STD_BG_COLOR;
                    app.StatusLabel.Text = 'Changes saved';
                    app.StatusLabel.FontColor = app.NORMAL_COLOR;
                otherwise
                    error('Unknown table status')
            end
        end       

        function saveInstrumentList(app)
            instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
            if ~isempty(app.TmpInstrList)
                InstrumentList = cell2struct(app.TmpInstrList, instr_names);
            else
                InstrumentList = struct();
            end
            dir = getLocalBaseDir();
            file_name = fullfile(dir,'LocalInstrumentControlSettings.mat');
            save(file_name, 'InstrumentList','-append');
            setInstrListStatus(app, 'edits_saved');
        end
    
        function loadInstrumentList(app)
            app.TmpInstrList = struct2cell(getLocalSettings('InstrumentList'));                 
            updateInstrDisp(app);
            setInstrListStatus(app, 'edits_saved');
        end
    
        %% find instrument dialog methods
        
        function initVisaMenus(app)
            app.AdapterDropDown.Enable = 'on';
            app.ConstructornameListBoxLabel.Text = 'Constructor name';
            ad = instrhwinfo('visa');
            app.AdapterDropDown.Items = ad.InstalledAdaptors;
            
            if ismember('ni',ad.InstalledAdaptors)
               app.AdapterDropDown.Value = 'ni'; 
            elseif isempty(ad.InstalledAdaptors)
                app.ConstructornameListBox.Items = {};
                app.RespErrWngTextArea.Value = sprintf('No VISA adaptors installed');
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findVisaGpibInstruments(app)
            
            % works with either 'visa' of 'gpib' values of InterfaceDropDown
            interface = app.InterfaceDropDown.Value;
            adaptor = app.AdapterDropDown.Value;
            if isempty(adaptor)
                setSearchIndicator(app,'completed');
                app.ConstructornameListBox.Items = {};
                app.RespErrWngTextArea.Value =...
                    sprintf('Instruments could not be found, no installed %s adaptors', upper(interface));
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
                return
            end
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo(interface, adaptor);
                app.ConstructornameListBox.Items = instr_list.ObjectConstructorName;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameListBox.Items = {};
                setSearchIndicator(app,'failed');
                app.RespErrWngTextArea.Value =...
                    sprintf('Could not find VISA or GPIB instruments with %s adaptor',adaptor);
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findSerialInstruments(app)
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo('serial');
                app.ConstructornameListBox.Items = instr_list.ObjectConstructorName;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameListBox.Items = {};
                setSearchIndicator(app,'failed');
                app.RespErrWngTextArea.Value = 'Could not find serial instruments';
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findTcpipUdpInstruments(app)
            % works with either 'visa' of 'gpib' values of InterfaceDropDown
            interface = app.InterfaceDropDown.Value;
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo(interface);
                app.ConstructornameListBox.Items = instr_list.LocalHost;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameListBox.Items = {};
                setSearchIndicator(app,'failed');
                app.RespErrWngTextArea.Value = 'Could not find TCPIP or UDP instruments';
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function setSearchIndicator(app, status)
            switch status
                case 'searching'
                    app.SearchstatusLabel.Text = 'Searching ...';
                    app.SearchstatusLabel.FontColor = app.WARNING_COLOR;
                case 'completed'
                    app.SearchstatusLabel.Text = 'Search completed';
                    app.SearchstatusLabel.FontColor = app.NORMAL_COLOR;
                case 'failed'
                    app.SearchstatusLabel.Text = 'Search unsuccessful';
                    app.SearchstatusLabel.FontColor = app.ERROR_COLOR;
                otherwise
                    error('Unknown search status')
            end
        end
        
        % Display result of identification query
        function updateRespField(app, resp_str, err_msg)
            if isempty(err_msg)
                app.RespErrWngTextArea.Value = resp_str;
                app.RespErrWngTextArea.FontColor = app.STD_TEXT_COLOR;
            else
                app.RespErrWngTextArea.Value = err_msg;
                app.RespErrWngTextArea.FontColor = app.ERROR_COLOR;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % The line below will throw an error if the settings file does not exist
            app.BaseDirEditField.Value = getLocalBaseDir();
            
            % Display instrument list
            loadInstrumentList(app);
        end

        % Button pushed function: AssignaddrButton
        function AssignaddrButtonPushed(app, event)
            ind = app.InstrListBox.Value;
            addr = app.ConstructornameListBox.Value;
            if ~isempty(ind) && ~isempty(addr)
                app.TmpInstrList{end}.interface = 'constructor';
                app.TmpInstrList{end}.address = addr;
            end
            updateInstrDisp(app);
            setInstrListStatus(app, 'edits_unsaved');
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            saveInstrumentList(app);
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            loadInstrumentList(app);
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            ind = app.InstrListBox.Value;
            if ~isempty(ind)
                app.TmpInstrList(ind) = [];
                updateInstrDisp(app);
                setInstrListStatus(app, 'edits_unsaved');
            end
        end

        % Value changed function: InstrListBox
        function InstrListBoxValueChanged(app, event)
            updateInstrDisp(app);
        end

        % Value changed function: AddressEditField, 
        % ControlclassEditField, DatasourceCheckBox, 
        % EnabledCheckBox, GUIEditField, InterfaceEditField, 
        % MenutitleEditField, NameEditField
        function InstrPropertyValueChanged(app, event)
            if ~isempty(app.InstrListBox.Value)
                updateTmpInstrList(app);
                instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
                app.InstrListBox.Items = instr_names;
            end
        end

        % Value changed function: InterfaceDropDown
        function InterfaceDropDownValueChanged(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            app.RespErrWngTextArea.Value = '';
            switch interface
                case {'visa', 'gpib'}
                    ad = instrhwinfo(interface);
                    app.AdapterDropDown.Items = ad.InstalledAdaptors;
                    app.AdapterDropDown.Enable = 'on';
                    app.AdapterDropDownLabel.Enable = 'on';
                    app.ConstructornameListBoxLabel.Text = 'Constructor name';
                    findVisaGpibInstruments(app);
                case 'serial'
                    app.AdapterDropDown.Enable = 'off';
                    app.AdapterDropDownLabel.Enable = 'off';
                    app.ConstructornameListBoxLabel.Text = 'Constructor name';
                    findSerialInstruments(app);
                case {'tcpip', 'udp'}
                    app.AdapterDropDown.Enable = 'off';
                    app.AdapterDropDownLabel.Enable = 'off';
                    app.ConstructornameListBoxLabel.Text = 'LocalHost';
                    findTcpipUdpInstruments(app);
            end
        end

        % Value changed function: AdapterDropDown
        function AdapterDropDownValueChanged(app, event)
            findVisaGpibInstruments(app);
            app.RespErrWngTextArea.Value = '';
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            switch interface
                case {'visa', 'gpib'}
                    findVisaGpibInstruments(app);
                case 'serial'
                    findSerialInstruments(app);
                case {'tcpip', 'udp'}
                    findTcpipUdpInstruments(app);
            end
        end

        % Button pushed function: IDNButton
        function IDNButtonPushed(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            app.RespErrWngTextArea.Value = '';
            switch interface
                case {'visa', 'gpib', 'serial'}
                    ocn = app.ConstructornameListBox.Value;
                    if isempty(ocn)
                        app.RespErrWngTextArea.Value = 'No constructor name given';
                        app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
                        return
                    end
                    
                    device = eval(ocn);
                    device.Timeout = app.DEFAULT_TIMEOUT; % s
                    
                    try
                        fopen(device);
                    catch 
                        app.RespErrWngTextArea.Value = 'Cannot open device';
                        app.RespErrWngTextArea.FontColor = app.ERROR_COLOR;
                        return
                    end
                    
                    [resp_str,~,msg] = query(device,'*IDN?');
                    updateRespField(app, resp_str, msg);
                    fclose(device);
                    delete(device);
                    
                case {'tcpip', 'udp'}
                    app.RespErrWngTextArea.Value = '*IDN? for TCPIP and UDP connection is not presently supported';
                    app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end

        % Button pushed function: IDNButton2
        function IDNButton2Pushed(app, event)
            % Sends IDN query to an instrument already in the InstrumentList
            ind = app.InstrListBox.Value;
            if ~isempty(ind)
                TmpEntry = app.TmpInstrList{ind};
                try
                    is_class_indicated = ~isempty(TmpEntry.control_class);
                catch
                    is_class_indicated = false;
                end
                if is_class_indicated
                    % if instrument class is indicated, create instrument-specific 
                    % communication object
                    
                    % Skip arguments that are empty
                    req_args={};
                    if ~isempty(TmpEntry.interface)
                        req_args=[req_args,{TmpEntry.interface}];
                    end
                    if ~isempty(TmpEntry.address)
                        req_args=[req_args,{TmpEntry.address}];
                    end
                    
                    TmpInstr = feval(TmpEntry.control_class, req_args{:});
                else
                    % Create generic MyInstrument object that assumes visa communication
                    TmpInstr = MyInstrument(TmpEntry.interface, TmpEntry.address);
                end
                
                try
                    % The convention is that idn method should open device, but still
                    % try explicitly opening the device before communication for robustness.
                    % Some instruments might not have a device object but still
                    % have an idn method, so suppress errors.
                    openDevice(TmpInstr);
                catch
                end
                
                try
                    TmpInstr.Device.Timeout = app.DEFAULT_TIMEOUT; % s
                catch
                end
                
                % Try calling idn method with one or two output arguments
                try
                    [resp_str, err_msg] = idn(TmpInstr);
                catch
                    try 
                        resp_str = idn(TmpInstr);
                        err_msg='';
                    catch
                        resp_str='';
                        err_msg=['Error when trying to communicate with ', TmpEntry.name];
                    end
                end
                % Display the result of communication attempt
                updateRespField(app, resp_str, err_msg);
                
                delete(TmpInstr)
            else
                app.RespErrWngTextArea.Value = 'No instrument selected from the list';
                app.RespErrWngTextArea.FontColor = app.WARNING_COLOR;
            end
        end

        % Button pushed function: AddinstrumentButton
        function AddinstrumentButtonPushed(app, event)
            % create a new empty instrument list entry with unique name
            instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
            
            tmp_name = matlab.lang.makeUniqueStrings('instrument', instr_names);
            app.TmpInstrList{end+1} = struct();
            app.TmpInstrList{end}.name = tmp_name;
            app.TmpInstrList{end}.interface = '';
            app.TmpInstrList{end}.address = '';
            updateInstrDisp(app);
            setInstrListStatus(app, 'edits_unsaved');
            
            % Move selection to the last item
            app.InstrListBox.Value=app.InstrListBox.ItemsData(end);
            
            updateInstrDisp(app);
        end

        % Button pushed function: StartupoptionsButton
        function StartupoptionsButtonPushed(app, event)
            ind = app.InstrListBox.Value;
            if isempty(ind)
                return
            end
            TmpEntry = app.TmpInstrList{ind};
            
            % Check if there are existing startup options
            if isfield(TmpEntry, 'StartupOpts')
                ExOpts = TmpEntry.StartupOpts;
            else
                ExOpts = struct();
            end

            % Call a dialog to input/modify auxiliary options 
            NewOpts = tabledlg(ExOpts, 'Name', 'Instrument settings');
            
            if ~isempty(NewOpts)
                app.TmpInstrList{ind}.StartupOpts = NewOpts;
                setInstrListStatus(app, 'edits_unsaved');
            end
        end

        % Button pushed function: LoggeroptionsButton
        function LoggeroptionsButtonPushed(app, event)
            ind = app.InstrListBox.Value;
            if isempty(ind)
                return
            end
            TmpEntry = app.TmpInstrList{ind};
            
            % Check if there are existing startup options
            if isfield(TmpEntry, 'LoggerOpts')
                ExOpts = TmpEntry.LoggerOpts;
            else
                ExOpts = struct();
            end

            % Call a dialog to input/modify auxiliary options 
            NewOpts = tabledlg(ExOpts, 'Name', 'Logger settings');
            
            if ~isempty(NewOpts)
                app.TmpInstrList{ind}.LoggerOpts = NewOpts;
                setInstrListStatus(app, 'edits_unsaved');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagethelistofinstrumentsUIFigure = uifigure('Visible', 'off');
            app.ManagethelistofinstrumentsUIFigure.Name = 'Manage the list of instruments';
            app.DeleteinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavechangesButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.ReloadlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.StatusLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.StatusLabel.Text = 'Status';
            app.InstrumentListlocationLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentListlocationLabel.Text = 'InstrumentList location';
            app.BaseDirEditField = uieditfield(app.ManagethelistofinstrumentsUIFigure, 'text');
            app.BaseDirEditField.Editable = 'off';
            app.InstrumentLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentLabel.Text = 'Instrument';
            app.InstrListBox = uilistbox(app.ManagethelistofinstrumentsUIFigure);
            app.InstrListBox.Items = {};
            app.InstrListBox.ValueChangedFcn = createCallbackFcn(app, @InstrListBoxValueChanged, true);
            app.InstrListBox.Value = {};
            app.TabGroup = uitabgroup(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentpropertiesTab = uitab(app.TabGroup);
            app.InstrumentpropertiesTab.Title = 'Instrument properties';
            app.DatasourceCheckBox = uicheckbox(app.InstrumentpropertiesTab);
            app.DatasourceCheckBox.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.DatasourceCheckBox.Text = 'Data source';
            app.DatasourceCheckBox.Value = true;
            app.InterfaceEditFieldLabel = uilabel(app.InstrumentpropertiesTab);
            app.InterfaceEditFieldLabel.Text = 'Interface';
            app.InterfaceEditField = uieditfield(app.InstrumentpropertiesTab, 'text');
            app.InterfaceEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.ControlclassEditFieldLabel = uilabel(app.InstrumentpropertiesTab);
            app.ControlclassEditFieldLabel.Text = 'Control class';
            app.ControlclassEditField = uieditfield(app.InstrumentpropertiesTab, 'text');
            app.ControlclassEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.GUIEditFieldLabel = uilabel(app.InstrumentpropertiesTab);
            app.GUIEditFieldLabel.Text = 'GUI';
            app.GUIEditField = uieditfield(app.InstrumentpropertiesTab, 'text');
            app.GUIEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.MenutitleEditFieldLabel = uilabel(app.InstrumentpropertiesTab);
            app.MenutitleEditFieldLabel.Text = 'Menu title';
            app.MenutitleEditField = uieditfield(app.InstrumentpropertiesTab, 'text');
            app.MenutitleEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.NameEditFieldLabel = uilabel(app.InstrumentpropertiesTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.InstrumentpropertiesTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.StartupoptionsButton = uibutton(app.InstrumentpropertiesTab, 'push');
            app.StartupoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @StartupoptionsButtonPushed, true);
            app.StartupoptionsButton.Text = 'Startup options';
            app.EnabledCheckBox = uicheckbox(app.InstrumentpropertiesTab);
            app.EnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.EnabledCheckBox.Text = 'Enabled';
            app.LoggeroptionsButton = uibutton(app.InstrumentpropertiesTab, 'push');
            app.LoggeroptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoggeroptionsButtonPushed, true);
            app.LoggeroptionsButton.Text = 'Logger options';
            app.FindinstrumentsTab = uitab(app.TabGroup);
            app.FindinstrumentsTab.Title = 'Find instruments';
            app.ConstructornameListBoxLabel = uilabel(app.FindinstrumentsTab);
            app.ConstructornameListBoxLabel.Text = 'Constructor name';
            app.ConstructornameListBox = uilistbox(app.FindinstrumentsTab);
            app.ConstructornameListBox.Items = {};
            app.ConstructornameListBox.Value = {};
            app.RefreshButton = uibutton(app.FindinstrumentsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.IDNButton = uibutton(app.FindinstrumentsTab, 'push');
            app.IDNButton.ButtonPushedFcn = createCallbackFcn(app, @IDNButtonPushed, true);
            app.IDNButton.Text = '*IDN?';
            app.AssignaddrButton = uibutton(app.FindinstrumentsTab, 'push');
            app.AssignaddrButton.ButtonPushedFcn = createCallbackFcn(app, @AssignaddrButtonPushed, true);
            app.AssignaddrButton.Text = 'Assign address';
            app.InterfaceDropDownLabel = uilabel(app.FindinstrumentsTab);
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.FindinstrumentsTab);
            app.InterfaceDropDown.Items = {'VISA', 'GPIB', 'Serial', 'TCPIP', 'UDP'};
            app.InterfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @InterfaceDropDownValueChanged, true);
            app.InterfaceDropDown.Value = 'VISA';
            app.AdapterDropDownLabel = uilabel(app.FindinstrumentsTab);
            app.AdapterDropDownLabel.Text = 'Adapter';
            app.AdapterDropDown = uidropdown(app.FindinstrumentsTab);
            app.AdapterDropDown.Items = {'ni', 'tektronix', 'agilent'};
            app.AdapterDropDown.ValueChangedFcn = createCallbackFcn(app, @AdapterDropDownValueChanged, true);
            app.AdapterDropDown.Value = 'ni';
            app.SearchstatusLabel = uilabel(app.FindinstrumentsTab);
            app.SearchstatusLabel.Text = 'Search status';
            app.AssignnewaddressLabel = uilabel(app.FindinstrumentsTab);
            app.AssignnewaddressLabel.Text = {'Assign new address '; 'to selected instrument'};
            app.ResponseerrorLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.ResponseerrorLabel.Text = 'Response/error';
            app.RespErrWngTextArea = uitextarea(app.ManagethelistofinstrumentsUIFigure);
            app.RespErrWngTextArea.Editable = 'off';
            app.IDNButton2 = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.IDNButton2.ButtonPushedFcn = createCallbackFcn(app, @IDNButton2Pushed, true);
            app.IDNButton2.Text = 'idn request';
            app.AddressEditFieldLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.ManagethelistofinstrumentsUIFigure, 'text');
            app.AddressEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.AddinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.AddinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AddinstrumentButtonPushed, true);
            app.AddinstrumentButton.Text = 'Add instrument';
            % Show the figure after all components are created
            app.ManagethelistofinstrumentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstrumentManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagethelistofinstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagethelistofinstrumentsUIFigure)
        end
    end
end